classdef VentanasInteres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficoVentanasInteres         matlab.ui.Figure
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        GrficayventanasdeintersdelasealLabel  matlab.ui.control.Label
        TiempoavisualizarLabel         matlab.ui.control.Label
        TiempoMostrar                  matlab.ui.control.NumericEditField
        Boton_Anterior                 matlab.ui.control.Button
        Boton_Siguiente                matlab.ui.control.Button
        VentanaanteriorLabel           matlab.ui.control.Label
        VentanasiguienteLabel          matlab.ui.control.Label
        AnotacionesLabel               matlab.ui.control.Label
        TextoAnotacion                 matlab.ui.control.Label
        EEGPlot1                       matlab.ui.control.UIAxes
        HoradeiniciodelregistroEEGHHMMSSEditFieldLabel  matlab.ui.control.Label
        HoradeiniciodelregistroEEGHHMMSSEditField  matlab.ui.control.EditField
        CanalavisualizarDropDownLabel  matlab.ui.control.Label
        CanalavisualizarDropDown       matlab.ui.control.DropDown
        sLabel                         matlab.ui.control.Label
        IctalDropDownLabel             matlab.ui.control.Label
        IctalColorDropDown             matlab.ui.control.DropDown
        IctalColorLamp                 matlab.ui.control.Lamp
        SanoDropDownLabel              matlab.ui.control.Label
        SanoColorDropDown              matlab.ui.control.DropDown
        PreictalLabel                  matlab.ui.control.Label
        PreictalColorDropDown          matlab.ui.control.DropDown
        InterictalLabel                matlab.ui.control.Label
        InterictalColorDropDown        matlab.ui.control.DropDown
        SanoColorLamp                  matlab.ui.control.Lamp
        PreictalColorLamp              matlab.ui.control.Lamp
        InterictalColorLamp            matlab.ui.control.Lamp
        GrillaCheckBox                 matlab.ui.control.CheckBox
        EEGPlot2                       matlab.ui.control.UIAxes
        LeyendadecolorTipodesealLabel  matlab.ui.control.Label
        MonoColorLamp                  matlab.ui.control.Lamp
        PoliColorLamp                  matlab.ui.control.Lamp
        LeyendadecolorMorfologaLabel   matlab.ui.control.Label
        MonomrficaLabel                matlab.ui.control.Label
        MonoColorDropDown              matlab.ui.control.DropDown
        PolimrficaLabel                matlab.ui.control.Label
        PoliColorDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        %App que abre esta app
        CallingApp        
        %Valor del numero de ventanas de 1 segundo posibles
        Cantidad_de_ventanas
        %Vectores con todos los limites inferiores 
        %y superiores para graficar el intervalo completo
        muestras_bot
        muestras_top
        %Vector con el numero de ventanas desplegadas
        no_ventana
        %Vector de tiempo para emplear como vector en x
        vector_tiempo_grafico
        %Canal actual a graficar
        canal_a_desplegar
        
        labels
        signal        
        window_samples
        fs_plot
        vector_ict
        vector_sano 
        Cantidad_de_ventanas_road = 1;
        sweepict = 1;
        sweepsano = 1;
        %Posicion del canal de interes respecto al vector de características
        canal_EEGVI
        %Colores de las clases para la clasificación supervisada
        ColorIctal = 'r';
        ColorSano = 'b';
        ColorPreictal = 'g';
        ColorInterictal = 'k';
        %Colores de las clases para la clasificación no supervisada
        ColorMono = 'm';
        ColorPoli = 'c';
        %Frecuencia entera
        Fs_entera
    end
    
    properties (Access = public)
        %Variables compartidas entre ventanas 
        Vector_Car_EEGVI
        EEG_VI
        Intervalo_Ventana_s
        Fs
        Tiempo_Inicio
        Vector_Car_EEGMOR
        %Canales analizados
        ch_int
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %Se obtienen las propiedades de la ventana anterior
            app.CallingApp = mainapp;    
            %Se llena la lista de canales disponibles a elegir
            app.CanalavisualizarDropDown.Items = app.CallingApp.ch_int;
            app.CanalavisualizarDropDown.Value = app.CallingApp.ch_int{1};
            %El canal a desplegar es el primero de la lista
            app.canal_a_desplegar = str2double(app.CanalavisualizarDropDown.Value);
            %Ya que el numero de canal no es el mismo respecto al vector de
            %etiquetas, se debe de encontrar este valor para la
            %lectura de la etiqueta de cada ventana
            for i=1:length(app.CanalavisualizarDropDown.Items)
                if str2double(app.CanalavisualizarDropDown.Items{i})==app.canal_a_desplegar
                    app.canal_EEGVI = i;
                end
            end            
            %Preparación a frecuencia entera:
            app.Fs_entera = floor(app.CallingApp.Fs);
            %Guardamos la cantidad de ventanas de 1s disponibles
            app.Cantidad_de_ventanas = length(app.CallingApp.Vector_Car_EEGVI); 
            %Se genera el vector de valores en x para las gráficas
            app.vector_tiempo_grafico = 1:1:app.Fs_entera*app.Cantidad_de_ventanas;
            %Generamos vectores vacíos para guardar todos los valores de
            %muestras para los limites superior e inferior de todas las
            %ventanas
            app.muestras_bot = zeros(1,app.Cantidad_de_ventanas);
            app.muestras_top = zeros(1,app.Cantidad_de_ventanas);
            %Ciclo para asignar los valores de muestras a los vectores
            for i=1:app.Cantidad_de_ventanas                
                if i == 1
                    app.muestras_bot(1,i) = 1;
                    app.muestras_top(1,i) = app.Fs_entera*1;
                else
                    app.muestras_bot(1,i) = app.muestras_top(1,i-1)+1;
                    app.muestras_top(1,i) = app.Fs_entera*i;
                end
            end            
            %Se inicia el vector de ventanas a desplegar, inicialmente es
            %[1,2,3,4,5]
            app.no_ventana = 1:1:floor(app.TiempoMostrar.Value);
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=str2double(strsplit(hora_inicial,{':',','}));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            %Ticks para el tiempo en el eje horizontal
            tick1 = hours(0)+hours(hh)+minutes(mm)+seconds(ss)+seconds(app.no_ventana(1)-1);
            tick2 = tick1 + seconds(((app.no_ventana(end)-app.no_ventana(1))/2)+app.no_ventana(1));
            tick3 = tick1 + seconds(app.no_ventana(end));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Button pushed function: Boton_Anterior
        function Boton_AnteriorButtonPushed(app, event)
            segmentos_entero = floor(app.TiempoMostrar.Value);
            array_ventanas = sort(app.no_ventana(1)-1:-1:app.no_ventana(1)-1-(segmentos_entero-1));
            if array_ventanas(1)<1
                %Mostrar alerta que se excede ya la señal y se queda igual
                %disp('ya valio');
                %Ir al final de la señal
                array_ventanas = sort(app.Cantidad_de_ventanas:-1:app.Cantidad_de_ventanas-(segmentos_entero-1));
            end
            app.no_ventana = array_ventanas;
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=str2double(strsplit(hora_inicial,{':',','}));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick0 + seconds(((app.no_ventana(end)-app.no_ventana(1))/2)+app.no_ventana(1));
            tick3 = tick0 + seconds(app.no_ventana(end));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Button pushed function: Boton_Siguiente
        function Boton_SiguienteButtonPushed(app, event)
            segmentos_entero = floor(app.TiempoMostrar.Value);
            array_ventanas = app.no_ventana(end)+1:1:app.no_ventana(end)+1+(segmentos_entero-1);
            if array_ventanas(end)>app.Cantidad_de_ventanas
                %Mostrar alerta que se excede ya la señal y se queda igual
                %disp('ya valio');
                %U volver al inicio de la señal
                array_ventanas = 1:1:1+(segmentos_entero-1);    
            end
            app.no_ventana = array_ventanas;
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=str2double(strsplit(hora_inicial,{':',','}));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick0 + seconds(((app.no_ventana(end)-app.no_ventana(1))/2)+app.no_ventana(1));
            tick3 = tick0 + seconds(app.no_ventana(end));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Value changed function: TiempoMostrar
        function TiempoMostrarValueChanged(app, event)
            segmentos_entero = floor(app.TiempoMostrar.Value);
            array_ventanas = app.no_ventana(1):1:app.no_ventana(1)+(segmentos_entero-1);       
            if array_ventanas(end)>app.Cantidad_de_ventanas
                %Mostrar alerta que se excede ya la señal y se queda igual
                %disp('ya valio');
            else
                app.no_ventana = array_ventanas;
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=str2double(strsplit(hora_inicial,{':',','}));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick0 + seconds(((app.no_ventana(end)-app.no_ventana(1))/2)+app.no_ventana(1));
            tick3 = tick0 + seconds(app.no_ventana(end));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Value changed function: 
        % HoradeiniciodelregistroEEGHHMMSSEditField
        function HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged(app, event)
            %Se hace la lectura del tiempo inicial
            hora_inicial = app.HoradeiniciodelregistroEEGHHMMSSEditField.Value;
            hhmmss=str2double(strsplit(hora_inicial,{':',','}));
            hh = hhmmss(1); %Horas del tiempo inicial
            mm = hhmmss(2); %Minutos del tiempo inicial
            ss = hhmmss(3); %Segundos del tiempo inicial
            
            %Ticks para el tiempo en el eje horizontal
            tick0 = hours(hh)+minutes(mm)+seconds(ss); %Tiempo inicial
            tick1 = tick0 + seconds(app.no_ventana(1)-1);
            tick2 = tick0 + seconds(((app.no_ventana(end)-app.no_ventana(1))/2)+app.no_ventana(1));
            tick3 = tick0 + seconds(app.no_ventana(end));
            time_series = app.vector_tiempo_grafico(1,app.muestras_bot(app.no_ventana(1)):app.muestras_top(app.no_ventana(end)));
            xticks_array = [time_series(1),time_series(round(length(time_series)/2)),time_series(length(time_series))];
            app.EEGPlot1.XTick = xticks_array;  
            app.EEGPlot1.XTickLabel ={datestr(tick1,'HH:MM:SS'),datestr(tick2,'HH:MM:SS'),datestr(tick3,'HH:MM:SS')};
            app.EEGPlot1.XLim = [time_series(1),time_series(end)];
            app.EEGPlot2.XTick = app.EEGPlot1.XTick;
            app.EEGPlot2.XTickLabel = app.EEGPlot1.XTickLabel;
            app.EEGPlot2.XLim = app.EEGPlot1.XLim;
        end

        % Value changed function: CanalavisualizarDropDown
        function CanalavisualizarDropDownValueChanged(app, event)
            app.canal_a_desplegar = str2double(app.CanalavisualizarDropDown.Value);
            %Ya que el numero de canal no es el mismo respecto al vector de
            %etiquetas, se debe de encontrar este valor para la
            %lectura de la etiqueta de cada ventana
            for i=1:length(app.CanalavisualizarDropDown.Items)
                if str2double(app.CanalavisualizarDropDown.Items{i})==app.canal_a_desplegar
                    app.canal_EEGVI = i;
                end
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
            
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
        end

        % Value changed function: IctalColorDropDown
        function IctalColorDropDownValueChanged(app, event)
            app.ColorIctal = app.IctalColorDropDown.Value;
            if app.ColorIctal == 'b'
                app.IctalColorLamp.Color = [0 0 1];
            elseif app.ColorIctal == 'k'
                app.IctalColorLamp.Color = [0 0 0];
            elseif app.ColorIctal == 'g'
                app.IctalColorLamp.Color = [0 1 0];
            elseif app.ColorIctal == 'r'
                app.IctalColorLamp.Color = [1 0 0];
            elseif app.ColorIctal == 'y'
                app.IctalColorLamp.Color = [1 1 0];
            elseif app.ColorIctal == 'c'
                app.IctalColorLamp.Color = [0 1 1];
            elseif app.ColorIctal == 'm'
                app.IctalColorLamp.Color = [1 0 1];
            else
                app.IctalColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: SanoColorDropDown
        function SanoColorDropDownValueChanged(app, event)
            app.ColorSano = app.SanoColorDropDown.Value;
            if app.ColorSano == 'b'
                app.SanoColorLamp.Color = [0 0 1];
            elseif app.ColorSano == 'k'
                app.SanoColorLamp.Color = [0 0 0];
            elseif app.ColorSano == 'g'
                app.SanoColorLamp.Color = [0 1 0];
            elseif app.ColorSano == 'r'
                app.SanoColorLamp.Color = [1 0 0];
            elseif app.ColorSano == 'y'
                app.SanoColorLamp.Color = [1 1 0];
            elseif app.ColorSano == 'c'
                app.SanoColorLamp.Color = [0 1 1];
            elseif app.ColorSano == 'm'
                app.SanoColorLamp.Color = [1 0 1];
            else
                app.SanoColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: PreictalColorDropDown
        function PreictalColorDropDownValueChanged(app, event)
            app.ColorPreictal = app.PreictalColorDropDown.Value;
            if app.ColorPreictal == 'b'
                app.PreictalColorLamp.Color = [0 0 1];
            elseif app.ColorPreictal == 'k'
                app.PreictalColorLamp.Color = [0 0 0];
            elseif app.ColorPreictal == 'g'
                app.PreictalColorLamp.Color = [0 1 0];
            elseif app.ColorPreictal == 'r'
                app.PreictalColorLamp.Color = [1 0 0];
            elseif app.ColorPreictal == 'y'
                app.PreictalColorLamp.Color = [1 1 0];
            elseif app.ColorPreictal == 'c'
                app.PreictalColorLamp.Color = [0 1 1];
            elseif app.ColorPreictal == 'm'
                app.PreictalColorLamp.Color = [1 0 1];
            else
                app.PreictalColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: InterictalColorDropDown
        function InterictalColorDropDownValueChanged(app, event)
            app.ColorInterictal = app.InterictalColorDropDown.Value;
            if app.ColorInterictal == 'b'
                app.InterictalColorLamp.Color = [0 0 1];
            elseif app.ColorInterictal == 'k'
                app.InterictalColorLamp.Color = [0 0 0];
            elseif app.ColorInterictal == 'g'
                app.InterictalColorLamp.Color = [0 1 0];
            elseif app.ColorInterictal == 'r'
                app.InterictalColorLamp.Color = [1 0 0];
            elseif app.ColorInterictal == 'y'
                app.InterictalColorLamp.Color = [1 1 0];
            elseif app.ColorInterictal == 'c'
                app.InterictalColorLamp.Color = [0 1 1];
            elseif app.ColorInterictal == 'm'
                app.InterictalColorLamp.Color = [1 0 1];
            else
                app.InterictalColorLamp.Color = [1 1 1];
            end
            
            %Graficamos cada ventana en los ejes (aprendizaje supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta ictal
                if app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorIctal); %Color
                %Si tiene etiqueta sano
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 2
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorSano);
                %Si tiene etiqueta preictal
                elseif app.CallingApp.Vector_Car_EEGVI(app.canal_EEGVI,w) == 3
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPreictal);
                %Si tiene etiqueta interictal
                else
                    plot(app.EEGPlot1,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorInterictal);
                end
                hold(app.EEGPlot1,'on');
            end
            hold(app.EEGPlot1,'off');
        end

        % Value changed function: GrillaCheckBox
        function GrillaCheckBoxValueChanged(app, event)
            value = app.GrillaCheckBox.Value;
            if value == 1  
                grid(app.EEGPlot1, 'on')
            else
                grid(app.EEGPlot1, 'off')
            end
        end

        % Value changed function: MonoColorDropDown
        function MonoColorDropDownValueChanged(app, event)
            app.ColorMono = app.MonoColorDropDown.Value;
            if app.ColorMono == 'b'
                app.MonoColorLamp.Color = [0 0 1];
            elseif app.ColorMono == 'k'
                app.MonoColorLamp.Color = [0 0 0];
            elseif app.ColorMono == 'g'
                app.MonoColorLamp.Color = [0 1 0];
            elseif app.ColorMono == 'r'
                app.MonoColorLamp.Color = [1 0 0];
            elseif app.ColorMono == 'y'
                app.MonoColorLamp.Color = [1 1 0];
            elseif app.ColorMono == 'c'
                app.MonoColorLamp.Color = [0 1 1];
            elseif app.ColorMono == 'm'
                app.MonoColorLamp.Color = [1 0 1];
            else
                app.MonoColorLamp.Color = [1 1 1];
            end
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
        end

        % Value changed function: PoliColorDropDown
        function PoliColorDropDownValueChanged(app, event)
            app.ColorPoli = app.PoliColorDropDown.Value;
            if app.ColorPoli == 'b'
                app.PoliColorLamp.Color = [0 0 1];
            elseif app.ColorPoli == 'k'
                app.PoliColorLamp.Color = [0 0 0];
            elseif app.ColorPoli == 'g'
                app.PoliColorLamp.Color = [0 1 0];
            elseif app.ColorPoli == 'r'
                app.PoliColorLamp.Color = [1 0 0];
            elseif app.ColorPoli == 'y'
                app.PoliColorLamp.Color = [1 1 0];
            elseif app.ColorPoli == 'c'
                app.PoliColorLamp.Color = [0 1 1];
            elseif app.ColorPoli == 'm'
                app.PoliColorLamp.Color = [1 0 1];
            else
                app.PoliColorLamp.Color = [1 1 1];
            end
            %*******************************************************************************************%
            %Graficamos cada ventana en los ejes (aprendizaje no supervisado)
            for i=1:length(app.no_ventana)
                w = app.no_ventana(i);
                plot_top = app.muestras_top(w);
                plot_bot = app.muestras_bot(w);
                %Si tiene etiqueta 1
                if app.CallingApp.Vector_Car_EEGMOR(app.canal_EEGVI,w) == 1
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorMono); %Color              
                %Si tiene etiqueta 2
                else
                    plot(app.EEGPlot2,... %Se indica a qué UIaxes graficar
                        app.vector_tiempo_grafico(plot_bot:plot_top),... %Eje X
                        app.CallingApp.EEG_VI(app.canal_a_desplegar,plot_bot:plot_top),... %Eje Y
                        app.ColorPoli);
                end
                hold(app.EEGPlot2,'on');
            end
            hold(app.EEGPlot2,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraficoVentanasInteres = uifigure('Visible', 'off');
            app.GraficoVentanasInteres.Name = 'Grafico EEG Con Anotaciones';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.GraficoVentanasInteres);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.GrficayventanasdeintersdelasealLabel = uilabel(app.GraficoVentanasInteres);
            app.GrficayventanasdeintersdelasealLabel.Text = 'Gráfica y ventanas de interés de la señal';
            app.TiempoavisualizarLabel = uilabel(app.GraficoVentanasInteres);
            app.TiempoavisualizarLabel.Text = 'Tiempo a visualizar';
            app.TiempoMostrar = uieditfield(app.GraficoVentanasInteres, 'numeric');
            app.TiempoMostrar.Limits = [1 120];
            app.TiempoMostrar.ValueChangedFcn = createCallbackFcn(app, @TiempoMostrarValueChanged, true);
            app.TiempoMostrar.Value = 10;
            app.Boton_Anterior = uibutton(app.GraficoVentanasInteres, 'push');
            app.Boton_Anterior.ButtonPushedFcn = createCallbackFcn(app, @Boton_AnteriorButtonPushed, true);
            app.Boton_Anterior.Icon = 'Flecha_izquierda.jpg';
            app.Boton_Anterior.Text = '';
            app.Boton_Siguiente = uibutton(app.GraficoVentanasInteres, 'push');
            app.Boton_Siguiente.ButtonPushedFcn = createCallbackFcn(app, @Boton_SiguienteButtonPushed, true);
            app.Boton_Siguiente.Icon = 'Flecha_derecha.jpg';
            app.Boton_Siguiente.Text = '';
            app.VentanaanteriorLabel = uilabel(app.GraficoVentanasInteres);
            app.VentanaanteriorLabel.Text = 'Ventana anterior';
            app.VentanasiguienteLabel = uilabel(app.GraficoVentanasInteres);
            app.VentanasiguienteLabel.Text = 'Ventana siguiente';
            app.AnotacionesLabel = uilabel(app.GraficoVentanasInteres);
            app.AnotacionesLabel.Text = 'Anotaciones:';
            app.TextoAnotacion = uilabel(app.GraficoVentanasInteres);
            app.EEGPlot1 = uiaxes(app.GraficoVentanasInteres);
            title(app.EEGPlot1, 'Señal EEG - Clasificación patológica')
            xlabel(app.EEGPlot1, 'Tiempo (HH:MM:SS)')
            ylabel(app.EEGPlot1, 'Voltaje (uV)')
            app.EEGPlot1.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.EEGPlot1.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel = uilabel(app.GraficoVentanasInteres);
            app.HoradeiniciodelregistroEEGHHMMSSEditFieldLabel.Text = {'Hora de inicio del registro EEG'; ' HH:MM:SS'};
            app.HoradeiniciodelregistroEEGHHMMSSEditField = uieditfield(app.GraficoVentanasInteres, 'text');
            app.HoradeiniciodelregistroEEGHHMMSSEditField.ValueChangedFcn = createCallbackFcn(app, @HoradeiniciodelregistroEEGHHMMSSEditFieldValueChanged, true);
            app.HoradeiniciodelregistroEEGHHMMSSEditField.Value = '00:00:00';
            app.CanalavisualizarDropDownLabel = uilabel(app.GraficoVentanasInteres);
            app.CanalavisualizarDropDownLabel.Text = 'Canal a visualizar';
            app.CanalavisualizarDropDown = uidropdown(app.GraficoVentanasInteres);
            app.CanalavisualizarDropDown.Items = {'-'};
            app.CanalavisualizarDropDown.ValueChangedFcn = createCallbackFcn(app, @CanalavisualizarDropDownValueChanged, true);
            app.CanalavisualizarDropDown.Value = '-';
            app.sLabel = uilabel(app.GraficoVentanasInteres);
            app.sLabel.Text = 's';
            app.IctalDropDownLabel = uilabel(app.GraficoVentanasInteres);
            app.IctalDropDownLabel.Text = 'Ictal';
            app.IctalColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.IctalColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.IctalColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.IctalColorDropDown.ValueChangedFcn = createCallbackFcn(app, @IctalColorDropDownValueChanged, true);
            app.IctalColorDropDown.Value = 'r';
            app.IctalColorLamp = uilamp(app.GraficoVentanasInteres);
            app.SanoDropDownLabel = uilabel(app.GraficoVentanasInteres);
            app.SanoDropDownLabel.Text = 'Sano';
            app.SanoColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.SanoColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.SanoColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.SanoColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SanoColorDropDownValueChanged, true);
            app.SanoColorDropDown.Value = 'b';
            app.PreictalLabel = uilabel(app.GraficoVentanasInteres);
            app.PreictalLabel.Text = 'Preictal';
            app.PreictalColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.PreictalColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.PreictalColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.PreictalColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PreictalColorDropDownValueChanged, true);
            app.PreictalColorDropDown.Value = 'g';
            app.InterictalLabel = uilabel(app.GraficoVentanasInteres);
            app.InterictalLabel.Text = 'Interictal';
            app.InterictalColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.InterictalColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.InterictalColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.InterictalColorDropDown.ValueChangedFcn = createCallbackFcn(app, @InterictalColorDropDownValueChanged, true);
            app.InterictalColorDropDown.Value = 'k';
            app.SanoColorLamp = uilamp(app.GraficoVentanasInteres);
            app.PreictalColorLamp = uilamp(app.GraficoVentanasInteres);
            app.InterictalColorLamp = uilamp(app.GraficoVentanasInteres);
            app.GrillaCheckBox = uicheckbox(app.GraficoVentanasInteres);
            app.GrillaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrillaCheckBoxValueChanged, true);
            app.GrillaCheckBox.Text = 'Grilla';
            app.EEGPlot2 = uiaxes(app.GraficoVentanasInteres);
            title(app.EEGPlot2, 'Señal EEG - Clasificación Morfológica')
            xlabel(app.EEGPlot2, 'Tiempo (HH:MM:SS)')
            ylabel(app.EEGPlot2, 'Voltaje (uV)')
            app.EEGPlot2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.EEGPlot2.YTickLabel = {'-300'; '-200'; '-100'; '0'; '100'; '200'; '300'};
            app.LeyendadecolorTipodesealLabel = uilabel(app.GraficoVentanasInteres);
            app.LeyendadecolorTipodesealLabel.Text = 'Leyenda de color: Tipo de señal';
            app.MonoColorLamp = uilamp(app.GraficoVentanasInteres);
            app.PoliColorLamp = uilamp(app.GraficoVentanasInteres);
            app.LeyendadecolorMorfologaLabel = uilabel(app.GraficoVentanasInteres);
            app.LeyendadecolorMorfologaLabel.Text = 'Leyenda de color: Morfología';
            app.MonomrficaLabel = uilabel(app.GraficoVentanasInteres);
            app.MonomrficaLabel.Text = 'Monomórfica';
            app.MonoColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.MonoColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.MonoColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.MonoColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MonoColorDropDownValueChanged, true);
            app.MonoColorDropDown.Value = 'm';
            app.PolimrficaLabel = uilabel(app.GraficoVentanasInteres);
            app.PolimrficaLabel.Text = 'Polimórfica';
            app.PoliColorDropDown = uidropdown(app.GraficoVentanasInteres);
            app.PoliColorDropDown.Items = {'Azul', 'Negro', 'Verde', 'Rojo', 'Amarillo', 'Cyan', 'Magenta', 'Blanco'};
            app.PoliColorDropDown.ItemsData = {'b', 'k', 'g', 'r', 'y', 'c', 'm', 'w'};
            app.PoliColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PoliColorDropDownValueChanged, true);
            app.PoliColorDropDown.Value = 'c';
            % Show the figure after all components are created
            app.GraficoVentanasInteres.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanasInteres(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficoVentanasInteres)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficoVentanasInteres)
        end
    end
end