classdef clasificadorventanainteres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClasificadorEEGInteres         matlab.ui.Figure
        CargarMATButton                matlab.ui.control.Button
        CargarEDFButton                matlab.ui.control.Button
        CargarClasificadorButton       matlab.ui.control.Button
        CargadelasealEEGLabel          matlab.ui.control.Label
        EPILECTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        HzIctal                        matlab.ui.control.Label
        TextoDimensionesEEG            matlab.ui.control.Label
        TextoTiempoEEG                 matlab.ui.control.Label
        FreqMuestreoFsEditFieldLabel   matlab.ui.control.Label
        FreqMuestreoFsIctalEditField   matlab.ui.control.NumericEditField
        ProcesarlasealEEGButton        matlab.ui.control.Button
        sLabel                         matlab.ui.control.Label
        TiempodeventanaEditFieldLabel  matlab.ui.control.Label
        TiempodeventanaEditField       matlab.ui.control.NumericEditField
        ObservaranotacionesButton      matlab.ui.control.Button
        SealEEGaAnalizarLabel          matlab.ui.control.Label
        CanalesdisponiblesLabel        matlab.ui.control.Label
        CanalesdisponiblesListBox      matlab.ui.control.ListBox
        CanalesaprocesarLabel          matlab.ui.control.Label
        CanalesaprocesarListBox        matlab.ui.control.ListBox
        Mas                            matlab.ui.control.StateButton
        Menos                          matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        %
        EEG_a_analizar
        Header_EEG
        Fs        
        
        %Caracteristicas
        cEEG
        muestras_eeg
        
        %Estructura con los resultados de un clasificador
        Struct_Clasificador
        
        %Aplicación de Ventanas de interes
        InterestWindows
        
        %Vector de caracteristicas para la app de ventanas de interes
        Vector_Car_EEGVI
        EEG_VI
        
        Intervalo_Ventana_s
        %Canales de interés
        ch_int
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarEDFButton
        function CargarEDFButtonPushed(app, event)
            %Abrir archivo EDF ICTAL
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                 disp('Usuario canceló la operacion');
            else
                 disp(['EL usuario seleccionó ', fullfile(path,file)]);
                [hdr, record] = edfread(strcat(path,file));
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.EEG_a_analizar = record;
                app.Header_EEG = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs = hdr.frequency(1);
                app.FreqMuestreoFsIctalEditField.Value = app.Fs;
                
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.EEG_a_analizar);
                numero_canalesEEG = min(datasize);
                ListBoxEEG  = num2cell(zeros(1,numero_canalesEEG));
                for i=1:numero_canalesEEG
                    ListBoxEEG{i} = i;
                end
                app.CanalesdisponiblesListBox.Items = ListBoxEEG;
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesEEG.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoEEG.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoEEG.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end                                
            end
            app.CanalesdisponiblesListBox.Enable = 'on';
            app.CanalesaprocesarListBox.Enable = 'on';
            app.Mas.Enable = 'on';
            app.Menos.Enable = 'on';
        end

        % Button pushed function: CargarMATButton
        function CargarMATButtonPushed(app, event)
            %Seleccionar archivo MAT ICTAL
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.EEG_a_analizar = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.Fs = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsIctalEditField.Value = app.Fs;
                
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.EEG_a_analizar);
                numero_canalesEEG = min(datasize);
                ListBoxEEG  = num2cell(zeros(1,numero_canalesEEG));
                for i=1:numero_canalesEEG
                    ListBoxEEG{i} = num2str(i);
                end
                app.CanalesdisponiblesListBox.Items = ListBoxEEG;
                
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesEEG.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoEEG.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoEEG.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoEEG.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end                                
            end
            app.CanalesdisponiblesListBox.Enable = 'on';
            app.CanalesaprocesarListBox.Enable = 'on';
            app.Mas.Enable = 'on';
            app.Menos.Enable = 'on';
        end

        % Button pushed function: ProcesarlasealEEGButton
        function ProcesarlasealEEGButtonPushed(app, event)
            app.EEG_a_analizar;
            app.Fs;
            %Extracción de Características
            s_ventana = app.TiempodeventanaEditField.Value;
            app.Intervalo_Ventana_s = s_ventana;
            app.cEEG = 1;%Sólo el canal 1 por el momento
            canales = 1; %Sólo 1 canal por el momento
            app.muestras_eeg =round(s_ventana*app.Fs);
            if((s_ventana*app.Fs-app.muestras_eeg)<0)
                app.muestras_eeg = app.muestras_eeg-1;
            end
            %Struct_Clasificador
            Vector_Opciones = app.Struct_Clasificador.Vector_op;
            AlgoritmoAA = app.Struct_Clasificador.Clasificador;
            if(app.Struct_Clasificador.Caracteristicas.Tipo_Caracteristicas == 0)
                [VectorCaracteristicas,Ventanadatos,~]=FeaturesV2(app.EEG_a_analizar,app.Fs,canales,app.muestras_eeg,app.cEEG,Vector_Opciones);
            else
                WaveletMadre = app.Struct_Clasificador.Caracteristicas.Madre;
                Niveles_des = app.Struct_Clasificador.Caracteristicas.Descomp;
                [VectorCaracteristicas,~,coeffEEG,~] = FeaturesV2wavelet(app.EEG_a_analizar,app.muestras_eeg,app.cEEG,WaveletMadre,Niveles_des,Vector_Opciones);
            end
            
            %Clasificación con el clasificador realizado
            if(app.Struct_Clasificador.Tipo_Clasificador == 0)
                %RNN
                VectorCaracteristicas = VectorCaracteristicas';
                Etiquetas_Generadas_EEGONEHOT = AlgoritmoAA(VectorCaracteristicas);
                %Se pasan las etiquetas de One-Hot a un vector con números
                %1, 2, 3 o 4, dependiendo de la cantidad de clases
                nwmax = length(VectorCaracteristicas);
                Etiquetas_Generadas_EEG = zeros(1,nwmax);
                for i=1:nwmax
                    ventana_interes = Etiquetas_Generadas_EEGONEHOT(:,i);
                    indice_max = find(ventana_interes==max(ventana_interes));
                    if 1 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 1;
                    elseif 2 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 2;
                    elseif 3 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 3;
                    elseif 4 == indice_max
                        Etiquetas_Generadas_EEG(1,i) = 4;
                    end
                end
               app.Vector_Car_EEGVI = Etiquetas_Generadas_EEG;
               app.EEG_VI = app.EEG_a_analizar;               
            elseif(app.Struct_Clasificador.Tipo_Clasificador == 1)
                %SVM
                VectorCaracteristicas = VectorCaracteristicas';
                Etiquetas_Generadas_EEG= predict(AlgoritmoAA, VectorCaracteristicas);                
            end        
            app.ObservaranotacionesButton.Enable = 'on';
        end

        % Button pushed function: CargarClasificadorButton
        function CargarClasificadorButtonPushed(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            espacio_trabajo_temp = load(strcat(path,file),'Clasificador_Entrenado');
            app.Struct_Clasificador = espacio_trabajo_temp.Clasificador_Entrenado;
        end

        % Button pushed function: ObservaranotacionesButton
        function ObservaranotacionesButtonPushed(app, event)
            canales_cell = str2double(app.CanalesaprocesarListBox.Items);
            %app.ch_int=canales_cell
            app.ch_int = app.CanalesaprocesarListBox.Items;
            app.InterestWindows = VentanasInteres(app);
        end

        % Value changed function: Mas
        function MasValueChanged(app, event)
            array_canales_disponibles = app.CanalesdisponiblesListBox.Items;
            array_canales_seleccionados = app.CanalesdisponiblesListBox.Value;
            for i=1:length(array_canales_disponibles)
                for j=1:length(array_canales_seleccionados)
                    array_canales_disponibles{i};
                    array_canales_seleccionados{j};
                    if ((~isempty(array_canales_disponibles{i})))
                        if((array_canales_disponibles{i}==array_canales_seleccionados{j}))
                           array_canales_disponibles{i} = []; 
                        end                        
                    end
                end
            end
            array_canales_disponibles=array_canales_disponibles(~cellfun('isempty',array_canales_disponibles));
            app.CanalesdisponiblesListBox.Items = array_canales_disponibles;
            for i=1:length(array_canales_seleccionados)
                app.CanalesaprocesarListBox.Items(length(app.CanalesaprocesarListBox.Items)+1) = array_canales_seleccionados(i);
            end
            app.CanalesaprocesarListBox.Items = sort(app.CanalesaprocesarListBox.Items);
            Procesados_nuevos = app.CanalesaprocesarListBox.Items;
            index2 = cell(size(Procesados_nuevos));
            numindex = str2double(Procesados_nuevos);
            sortedstr = sort(numindex);
            for i=1:length(index2)
                index2{i} = num2str(sortedstr(i));
            end
            app.CanalesaprocesarListBox.Items = index2;
        end

        % Value changed function: Menos
        function MenosValueChanged(app, event)
            array_canales_procesar = app.CanalesaprocesarListBox.Items;
            array_canales_seleccionados = app.CanalesaprocesarListBox.Value;
            for i=1:length(array_canales_procesar)
                for j=1:length(array_canales_seleccionados)
                    array_canales_procesar{i};
                    array_canales_seleccionados{j};
                    if ((~isempty(array_canales_procesar{i})))
                        if((array_canales_procesar{i}==array_canales_seleccionados{j}))
                           array_canales_procesar{i} = []; 
                        end                        
                    end
                end
            end
            array_canales_procesar=array_canales_procesar(~cellfun('isempty',array_canales_procesar));
            app.CanalesaprocesarListBox.Items = array_canales_procesar;
            for i=1:length(array_canales_seleccionados)
                app.CanalesdisponiblesListBox.Items(length(app.CanalesdisponiblesListBox.Items)+1) = array_canales_seleccionados(i);
            end
            Disponibles_nuevos = app.CanalesdisponiblesListBox.Items;
            index2 = cell(size(Disponibles_nuevos));
            numindex = str2double(Disponibles_nuevos);
            sortedstr = sort(numindex);
            for i=1:length(index2)
                index2{i} = num2str(sortedstr(i));
            end
            app.CanalesdisponiblesListBox.Items = index2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClasificadorEEGInteres = uifigure('Visible', 'off');
            app.ClasificadorEEGInteres.Name = 'UI Figure';
            app.CargarMATButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargarMATButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATButtonPushed, true);
            app.CargarMATButton.Text = 'Cargar MAT';
            app.CargarEDFButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargarEDFButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFButtonPushed, true);
            app.CargarEDFButton.Text = 'Cargar EDF';
            app.CargarClasificadorButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.CargarClasificadorButton.ButtonPushedFcn = createCallbackFcn(app, @CargarClasificadorButtonPushed, true);
            app.CargarClasificadorButton.Text = 'Cargar Clasificador';
            app.CargadelasealEEGLabel = uilabel(app.ClasificadorEEGInteres);
            app.CargadelasealEEGLabel.Text = 'Carga de la señal EEG';
            app.EPILECTICEEGANALYSISTOOLBOXLabel = uilabel(app.ClasificadorEEGInteres);
            app.EPILECTICEEGANALYSISTOOLBOXLabel.Text = 'EPILECTIC EEG ANALYSIS TOOLBOX';
            app.HzIctal = uilabel(app.ClasificadorEEGInteres);
            app.HzIctal.Text = 'Hz';
            app.TextoDimensionesEEG = uilabel(app.ClasificadorEEGInteres);
            app.TextoDimensionesEEG.Text = 'Tamaño: - x -';
            app.TextoTiempoEEG = uilabel(app.ClasificadorEEGInteres);
            app.TextoTiempoEEG.Text = 'Duración: -';
            app.FreqMuestreoFsEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.FreqMuestreoFsEditFieldLabel.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsIctalEditField = uieditfield(app.ClasificadorEEGInteres, 'numeric');
            app.ProcesarlasealEEGButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.ProcesarlasealEEGButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarlasealEEGButtonPushed, true);
            app.ProcesarlasealEEGButton.Text = 'Procesar la señal EEG';
            app.sLabel = uilabel(app.ClasificadorEEGInteres);
            app.sLabel.Text = 's';
            app.TiempodeventanaEditFieldLabel = uilabel(app.ClasificadorEEGInteres);
            app.TiempodeventanaEditFieldLabel.Text = 'Tiempo de ventana';
            app.TiempodeventanaEditField = uieditfield(app.ClasificadorEEGInteres, 'numeric');
            app.TiempodeventanaEditField.Value = 1;
            app.ObservaranotacionesButton = uibutton(app.ClasificadorEEGInteres, 'push');
            app.ObservaranotacionesButton.ButtonPushedFcn = createCallbackFcn(app, @ObservaranotacionesButtonPushed, true);
            app.ObservaranotacionesButton.Enable = 'off';
            app.ObservaranotacionesButton.Text = 'Observar anotaciones';
            app.SealEEGaAnalizarLabel = uilabel(app.ClasificadorEEGInteres);
            app.SealEEGaAnalizarLabel.Text = 'Señal EEG a Analizar';
            app.CanalesdisponiblesLabel = uilabel(app.ClasificadorEEGInteres);
            app.CanalesdisponiblesLabel.Text = {'Canales '; 'disponibles'};
            app.CanalesdisponiblesListBox = uilistbox(app.ClasificadorEEGInteres);
            app.CanalesdisponiblesListBox.Items = {};
            app.CanalesdisponiblesListBox.Multiselect = 'on';
            app.CanalesdisponiblesListBox.Enable = 'off';
            app.CanalesdisponiblesListBox.Value = {};
            app.CanalesaprocesarLabel = uilabel(app.ClasificadorEEGInteres);
            app.CanalesaprocesarLabel.Text = {'Canales'; 'a procesar'};
            app.CanalesaprocesarListBox = uilistbox(app.ClasificadorEEGInteres);
            app.CanalesaprocesarListBox.Items = {};
            app.CanalesaprocesarListBox.Multiselect = 'on';
            app.CanalesaprocesarListBox.Enable = 'off';
            app.CanalesaprocesarListBox.Value = {};
            app.Mas = uibutton(app.ClasificadorEEGInteres, 'state');
            app.Mas.ValueChangedFcn = createCallbackFcn(app, @MasValueChanged, true);
            app.Mas.Enable = 'off';
            app.Mas.Text = '+';
            app.Menos = uibutton(app.ClasificadorEEGInteres, 'state');
            app.Menos.ValueChangedFcn = createCallbackFcn(app, @MenosValueChanged, true);
            app.Menos.Enable = 'off';
            app.Menos.Text = '-';
            % Show the figure after all components are created
            app.ClasificadorEEGInteres.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clasificadorventanainteres

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClasificadorEEGInteres)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClasificadorEEGInteres)
        end
    end
end