classdef principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Principal                     matlab.ui.Figure
        AgregarnuevopacienteButton    matlab.ui.control.Button
        ConsultarButton               matlab.ui.control.Button
        AnalizarpruebaButton          matlab.ui.control.Button
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        ConectarButton                matlab.ui.control.StateButton
        DesconectarButton             matlab.ui.control.Button
        AnotacionesAutomaticasButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        AgregarPaciente
        RealizarConsulta
        AnalizarPrueba
        MainAnotapp
    end
    
    properties (Access = public)
        Conexion % Description
        MLTesteo = 770;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DesconectarButton.Enable = 'off';
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'on';
        end

        % Button pushed function: AgregarnuevopacienteButton
        function AgregarnuevopacienteButtonPushed(app, event)
            % Deshabilitar botones
            app.DesconectarButton.Enable = 'off';
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            
            app.AgregarPaciente = agregarpac(app,app.Conexion);
            
        end

        % Close request function: Principal
        function PrincipalCloseRequest(app, event)
            % Close database connection
            close(app.Conexion);
            clear app.Conexion
            % Delete apps
            delete(app.AgregarPaciente)
            delete(app.RealizarConsulta)
            delete(app.AnalizarPrueba)
            delete(app)
        end

        % Button pushed function: ConsultarButton
        function ConsultarButtonPushed(app, event)
            % Deshabilitar botones
            app.DesconectarButton.Enable = 'off';
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.RealizarConsulta = consultas(app,app.Conexion);
        end

        % Button pushed function: AnalizarpruebaButton
        function AnalizarpruebaButtonPushed(app, event)
            % Deshabilitar botones
            app.DesconectarButton.Enable = 'off';
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AnalizarPrueba = procesamiento(app,app.Conexion);
        end

        % Value changed function: ConectarButton
        function ConectarButtonValueChanged(app, event)
            value = app.ConectarButton.Value;
            if value==1
                fileID = fopen('conexion.csv','r');
                conn = textscan(fileID,'%s %s %s','Delimiter',',');
                fclose(fileID);
                
                condb = sprintf('%s',cell2mat(conn{1,1}));
                user = sprintf('%s',cell2mat(conn{1,2}));
                password = sprintf('%s',cell2mat(conn{1,3}));
                
                try
                    % configureODBCDataSource
                    con = database(condb, user, password);
                    query = ['SELECT * FROM humana.pacientes'];
                    data = fetch(con,query);
                    % disp('Conexión realizada correctamente');
                    msgbox('Conexión realizada correctamente.', 'Mensaje');
                    app.Conexion = con;
                    clear query
                    % Open the options dialog and pass inputs
                    app.DesconectarButton.Enable = 'on';
                    app.AgregarnuevopacienteButton.Enable = 'on';
                    app.ConsultarButton.Enable = 'on';
                    app.AnalizarpruebaButton.Enable = 'on';
                catch exception
                    if (strcmp(exception.identifier, 'database:database:ODBCDriverError'))
                        % disp('Ha ocurrido u error durante la conexión a la base de datos: ODBCDriverError');
                        errordlg(['Ha ocurrido un error durante la conexión a la base de datos. ' ...
                            'Verifique que la base de datos "HUMANA" esté creada con sus respectivas tablas.'],'Error');
                        app.ConectarButton.Value=0;
                        return;
                    elseif (strcmp(exception.identifier, 'database:database:dataSourceNameNotFound'))
                        % disp('Ha ocurrido un error durante la conexión a la base de datos: dataSourceNameNotFound. Por favor configure una conexión ODBC de nombre toolbox');
                        errordlg(['Ha ocurrido un error durante la conexión a la base de datos. ' ...
                            'Configure una conexión ODBC con el nombre "toolbox".'],'Error');
                        app.ConectarButton.Value=0;
                        return;
                    end
                    
                    rethrow(exception);
                end
            else
                % Close database connection
                close(app.Conexion);
                clear app.Conexion
                app.DesconectarButton.Enable = 'off';
                app.AgregarnuevopacienteButton.Enable = 'off';
                app.ConsultarButton.Enable = 'off';
                app.AnalizarpruebaButton.Enable = 'on';
            end
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            % Close database connection
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'on';
            close(app.Conexion);
            clear app.Conexion
            app.ConectarButton.Value = false;
            app.DesconectarButton.Enable = 'off';
        end

        % Button pushed function: AnotacionesAutomaticasButton
        function AnotacionesAutomaticasButtonPushed(app, event)
            app.MainAnotapp = loginanotaciones(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Principal = uifigure('Visible', 'off');
            app.Principal.Name = 'Principal';
            app.Principal.CloseRequestFcn = createCallbackFcn(app, @PrincipalCloseRequest, true);
            app.AgregarnuevopacienteButton = uibutton(app.Principal, 'push');
            app.AgregarnuevopacienteButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarnuevopacienteButtonPushed, true);
            app.AgregarnuevopacienteButton.Text = 'Agregar nuevo paciente';
            app.ConsultarButton = uibutton(app.Principal, 'push');
            app.ConsultarButton.ButtonPushedFcn = createCallbackFcn(app, @ConsultarButtonPushed, true);
            app.ConsultarButton.Text = 'Consultar';
            app.AnalizarpruebaButton = uibutton(app.Principal, 'push');
            app.AnalizarpruebaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarpruebaButtonPushed, true);
            app.AnalizarpruebaButton.Text = 'Analizar prueba';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.Principal);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.Image = uiimage(app.Principal);
            app.Image.ImageSource = 'logouvg.jpg';
            app.ConectarButton = uibutton(app.Principal, 'state');
            app.ConectarButton.ValueChangedFcn = createCallbackFcn(app, @ConectarButtonValueChanged, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.Principal, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.AnotacionesAutomaticasButton = uibutton(app.Principal, 'push');
            app.AnotacionesAutomaticasButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesAutomaticasButtonPushed, true);
            app.AnotacionesAutomaticasButton.Text = 'Anotaciones Automaticas';
            % Show the figure after all components are created
            app.Principal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = principal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Principal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Principal)
        end
    end
end