classdef admin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdministrarusuariosUIFigure   matlab.ui.Figure
        CerrarButton                  matlab.ui.control.Button
        AdministracindeusuariosLabel  matlab.ui.control.Label
        DesbloquearButton             matlab.ui.control.Button
        BloquearButton                matlab.ui.control.Button
        EliminarButton                matlab.ui.control.Button
        NuevoButton                   matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp;
        datos;
        Conexion;
        p = table();
        NuevoUsuario;
    end
    
    methods (Access = public)
        
        function ActivarNuevoU(app)
            app.NuevoButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.AdministrarusuariosUIFigure,"center");
            app.CallingApp = mainapp;
            app.Conexion = con;
            
            temp_estado = "";
            
            %query = 'SELECT User, account_locked FROM mysql.user WHERE User NOT LIKE "mysql%"';
            query = 'SELECT u.User, u.account_locked, u2.tipo FROM mysql.user u, humana.usuarios u2 WHERE u.User NOT LIKE "mysql%" and u.`User` = u2.usuario;';
           
            data = fetch(con,query);
            
            dimension=size(data);
            for i=1:dimension(1)
                if string(data.account_locked(i)) == 'N'
                    temp_estado = 'Y';
                else
                    temp_estado = 'N';
                end
                app.p = [app.p; {i, string(data.User(i)), string(data.tipo(i)), temp_estado, false}];
            end
            app.UITable.Data = app.p;
        end

        % Button pushed function: NuevoButton
        function NuevoButtonPushed(app, event)
%             app.NuevoButton.Enable = 'off';
            app.NuevoUsuario = nuevou(app, app.Conexion);
            delete(app);
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
            ActivarAdminU(app.CallingApp);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdministrarusuariosUIFigure = uifigure('Visible', 'off');
            app.AdministrarusuariosUIFigure.Name = 'Administrar usuarios';
            app.UITable = uitable(app.AdministrarusuariosUIFigure);
            app.UITable.ColumnName = {'#'; 'Usuario'; 'Tipo'; 'Activo'; 'Seleccionar'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false];
            app.UITable.ColumnEditable = [false false false false true];
            app.NuevoButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.NuevoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoButtonPushed, true);
            app.NuevoButton.Text = 'Nuevo';
            app.EliminarButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.EliminarButton.Text = 'Eliminar';
            app.BloquearButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.BloquearButton.Text = 'Bloquear';
            app.DesbloquearButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.DesbloquearButton.Text = 'Desbloquear';
            app.AdministracindeusuariosLabel = uilabel(app.AdministrarusuariosUIFigure);
            app.AdministracindeusuariosLabel.Text = 'Administración de usuarios';
            app.CerrarButton = uibutton(app.AdministrarusuariosUIFigure, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            % Show the figure after all components are created
            app.AdministrarusuariosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = admin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdministrarusuariosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdministrarusuariosUIFigure)
        end
    end
end