classdef agregarpac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuevoPaciente                   matlab.ui.Figure
        FechadenacimientoDatePicker     matlab.ui.control.DatePicker
        FechadenacimientoDatePickerLabel  matlab.ui.control.Label
        RegresarButton                  matlab.ui.control.Button
        NuevoButton                     matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        CondicindelpacienteButtonGroup  matlab.ui.container.ButtonGroup
        PadecedeepilepsiaButton         matlab.ui.control.RadioButton
        NopadecedeepilepsiaButton       matlab.ui.control.RadioButton
        DiagnsticoTextArea              matlab.ui.control.TextArea
        DiagnsticoTextAreaLabel         matlab.ui.control.Label
        preescripcinLabel               matlab.ui.control.Label
        deLabel                         matlab.ui.control.Label
        AadirnuevopacienteLabel         matlab.ui.control.Label
        patolgicosLabel                 matlab.ui.control.Label
        personalesLabel                 matlab.ui.control.Label
        AntecedentesTextArea            matlab.ui.control.TextArea
        AntecedentesTextAreaLabel       matlab.ui.control.Label
        SexoButtonGroup                 matlab.ui.container.ButtonGroup
        MButton                         matlab.ui.control.RadioButton
        FButton                         matlab.ui.control.RadioButton
        CdigodepacienteEditField        matlab.ui.control.EditField
        CdigodepacienteEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        Conexion
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            app.CallingApp = mainapp;
            app.Conexion = con;
        end

        % Close request function: NuevoPaciente
        function NuevoPacienteCloseRequest(app, event)
            % Enable the Plot Opions button in main app
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
%             app.CallingApp.AgregarnuevopacienteButton.Enable = 'on';
%             app.CallingApp.ConsultarButton.Enable = 'on';
%             app.CallingApp.AnalizarpruebaButton.Enable = 'on';
%             app.CallingApp.DesconectarButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            id = app.CdigodepacienteEditField.Value;
            sexo = app.SexoButtonGroup.SelectedObject.Text;
            fenac = datestr(app.FechadenacimientoDatePicker.Value,29);
            antpat = app.AntecedentesTextArea.Value;
            diagn = app.DiagnsticoTextArea.Value;
            cond = app.CondicindelpacienteButtonGroup.SelectedObject.Text;
            switch cond
                case 'No padece de epilepsia'
                    enf = 'SANO';
                case 'Padece de epilepsia'
                    enf = 'ENFERMO';
                otherwise
                    enf = '';
            end
            
            % Realizar búsqueda de la base de datos
            conn = app.Conexion;
            query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id...
                '"'];
            data = fetch(conn,query);
            
            
            if((isempty(id)==1) || (isempty(fenac)==1) || (isempty(enf)==1))
                errordlg('Ingrese todos los campos','Error');
            else
                if(isempty(data)==0)
                    errordlg('ID Paciente ya existente','Error');
                else
                    nuevo={id,sexo,fenac,antpat{1,1},diagn{1,1},enf};
                    columnas={'id_paciente','sexo','fecha_nacimiento','antecedentes','diagnostico','condicion'};
                    insert(conn,'humana.pacientes',columnas,nuevo);
                    %close(conn);
                    msgbox('Se ha guardado correctamente','Mensaje');
                    clear conn query
                end
            end

        end

        % Button pushed function: NuevoButton
        function NuevoButtonPushed(app, event)
            app.CdigodepacienteEditField.Value = '';
            app.AntecedentesTextArea.Value = '';
            app.DiagnsticoTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NuevoPaciente = uifigure('Visible', 'off');
            app.NuevoPaciente.Name = 'Nuevo Paciente';
            app.NuevoPaciente.CloseRequestFcn = createCallbackFcn(app, @NuevoPacienteCloseRequest, true);
            app.CdigodepacienteEditFieldLabel = uilabel(app.NuevoPaciente);
            app.CdigodepacienteEditFieldLabel.Text = 'Código de paciente';
            app.CdigodepacienteEditField = uieditfield(app.NuevoPaciente, 'text');
            app.SexoButtonGroup = uibuttongroup(app.NuevoPaciente);
            app.SexoButtonGroup.Title = 'Sexo';
            app.FButton = uiradiobutton(app.SexoButtonGroup);
            app.FButton.Text = 'F';
            app.FButton.Value = true;
            app.MButton = uiradiobutton(app.SexoButtonGroup);
            app.MButton.Text = 'M';
            app.AntecedentesTextAreaLabel = uilabel(app.NuevoPaciente);
            app.AntecedentesTextAreaLabel.Text = 'Antecedentes';
            app.AntecedentesTextArea = uitextarea(app.NuevoPaciente);
            app.personalesLabel = uilabel(app.NuevoPaciente);
            app.personalesLabel.Text = 'personales';
            app.patolgicosLabel = uilabel(app.NuevoPaciente);
            app.patolgicosLabel.Text = 'patológicos';
            app.AadirnuevopacienteLabel = uilabel(app.NuevoPaciente);
            app.AadirnuevopacienteLabel.Text = 'Añadir nuevo paciente';
            app.deLabel = uilabel(app.NuevoPaciente);
            app.deLabel.Text = 'de';
            app.preescripcinLabel = uilabel(app.NuevoPaciente);
            app.preescripcinLabel.Text = 'preescripción';
            app.DiagnsticoTextAreaLabel = uilabel(app.NuevoPaciente);
            app.DiagnsticoTextAreaLabel.Text = 'Diagnóstico';
            app.DiagnsticoTextArea = uitextarea(app.NuevoPaciente);
            app.CondicindelpacienteButtonGroup = uibuttongroup(app.NuevoPaciente);
            app.CondicindelpacienteButtonGroup.Title = 'Condición del paciente';
            app.NopadecedeepilepsiaButton = uiradiobutton(app.CondicindelpacienteButtonGroup);
            app.NopadecedeepilepsiaButton.Text = 'No padece de epilepsia';
            app.NopadecedeepilepsiaButton.Value = true;
            app.PadecedeepilepsiaButton = uiradiobutton(app.CondicindelpacienteButtonGroup);
            app.PadecedeepilepsiaButton.Text = 'Padece de epilepsia';
            app.GuardarButton = uibutton(app.NuevoPaciente, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NuevoButton = uibutton(app.NuevoPaciente, 'push');
            app.NuevoButton.ButtonPushedFcn = createCallbackFcn(app, @NuevoButtonPushed, true);
            app.NuevoButton.Text = 'Nuevo';
            app.RegresarButton = uibutton(app.NuevoPaciente, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.FechadenacimientoDatePickerLabel = uilabel(app.NuevoPaciente);
            app.FechadenacimientoDatePickerLabel.Text = 'Fecha de nacimiento';
            app.FechadenacimientoDatePicker = uidatepicker(app.NuevoPaciente);
            app.FechadenacimientoDatePicker.DisplayFormat = 'uuuu-MM-dd';
            % Show the figure after all components are created
            app.NuevoPaciente.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = agregarpac(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NuevoPaciente)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.NuevoPaciente)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuevoPaciente)
        end
    end
end