classdef credenciales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cred                     matlab.ui.Figure
        Recuperar                matlab.ui.control.Button
        RecuperarcontraseaLabel  matlab.ui.control.Label
        CorreoEditField          matlab.ui.control.EditField
        CorreoEditFieldLabel     matlab.ui.control.Label
        Hyperlink                matlab.ui.control.Hyperlink
        DebugEditField           matlab.ui.control.EditField
        DebugEditFieldLabel      matlab.ui.control.Label
        IntroduzcasuscredencialesdeaccesoLabel  matlab.ui.control.Label
        ContraseaEditField       matlab.ui.control.EditField
        ContraseaEditFieldLabel  matlab.ui.control.Label
        UsuarioEditField         matlab.ui.control.EditField
        UsuarioEditFieldLabel    matlab.ui.control.Label
        ConectarButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.cred,"center");
            app.CallingApp = mainapp;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            try
                % configureODBCDataSource
                con = database('toolbox', app.UsuarioEditField.Value, app.DebugEditField.Value);
                % disp('Conexión realizada correctamente');
                if ~isempty(con.UserName)
                    msgbox('Conexión realizada correctamente.', 'Mensaje');
                else
                    ME = MException('(3) Error datos no válidos', ...
                        'Revise las credenciales','Error');
                    throw(ME)
                end
                %app.Conexion = con;
                clear query
                activar(app.CallingApp, con, app.UsuarioEditField.Value)
                delete(app)
            catch exception
                if (strcmp(exception.identifier, 'database:database:ODBCDriverError'))
                    % disp('Ha ocurrido u error durante la conexión a la base de datos: ODBCDriverError');
                    errordlg(['(1) Ha ocurrido un error durante la conexión a la base de datos. ' ...
                        'Verifique que la base de datos "HUMANA" esté creada con sus respectivas tablas.'],'Error');
                    app.ConectarButton.Value=0;
                    return;
                elseif (strcmp(exception.identifier, 'database:database:dataSourceNameNotFound'))
                    % disp('Ha ocurrido un error durante la conexión a la base de datos: dataSourceNameNotFound. Por favor configure una conexión ODBC de nombre toolbox');
                    errordlg(['(2) Ha ocurrido un error durante la conexión a la base de datos. ' ...
                        'Configure una conexión ODBC con el nombre "toolbox".'],'Error');
                    app.ConectarButton.Value=0;
                    return;
                else
                    errordlg(['Error de inicio de sesión. ' ...
                    'Revise sus credenciales.'],'Error');
                end
                
                rethrow(exception);
            end
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
            temp = "*";
            changingValue = event.Value;
            if strlength(app.DebugEditField.Value) ~= 0
                if strlength(changingValue) < strlength(app.DebugEditField.Value)
                    if strlength(changingValue) == 0
                        app.DebugEditField.Value = "";
                    else
                        app.DebugEditField.Value = app.DebugEditField.Value(1:strlength(changingValue));
                    end
                else
                    app.DebugEditField.Value = strcat(app.DebugEditField.Value, changingValue(end));
                end
            else
                app.DebugEditField.Value = changingValue;
            end
            if strlength(changingValue)>0
                for i=1:strlength(changingValue)-1
                    temp = strcat(temp,"*");
                end
                app.ContraseaEditField.Value = temp;
            else
                app.ContraseaEditField.Value = "";
            end
        end

        % Close request function: cred
        function credCloseRequest(app, event)
            cancelarLogin(app.CallingApp);
            delete(app);
        end

        % Callback function: Hyperlink
        function HyperlinkClicked(app, event)
            if (strcmp(app.UsuarioEditField.Value,''))
                errordlg(['Error. Por favor ingrese el usuario ' ...
                'para el cual desea recuperar la contraseña.'],'Error');
            else
%                 query = ['SELECT correo ' ...
%                 'FROM humana.usuarios WHERE usuario= "' ...
%                 char(app.UsuarioEditField.Value)...
%                 '"'];
%                 data = fetch(app.Conexion,query)
%                 string(data.correo);
%                 
                setpref('Internet','SMTP_Server','smtp.gmail.com');
                setpref('Internet','E_mail','eeganalysistoolbox@gmail.com');
                setpref('Internet','SMTP_Username','eeganalysistoolbox@gmail.com');
                setpref('Internet','SMTP_Password','uvg@2022');
                props = java.lang.System.getProperties;
                props.setProperty('mail.smtp.auth','true');
                props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
                props.setProperty('mail.smtp.socketFactory.port','465');
                
                sendmail('man13600@uvg.edu.gt','EEG Analysis Toolbox - Recuperar contraseña', ['Tu nueva contrseña es "1234". ' ...
                    'Puedes cambiarla en cualquier momento en la pantalla de configuración despues de iniciar sesión.']);
                
                msgbox('Si el usuario existe recibirá una contraseña temporal al correo asociado. De no recibir el correo revisa tu conexión a internet y tu buzón de correo no deseado.', 'Mensaje');
            end
        end

        % Button pushed function: Recuperar
        function RecuperarButtonPushed(app, event)
            msgbox('Si el usuario existe recibirá una contraseña temporal al correo asociado. De no recibir el correo revisa tu conexión a internet y tu buzón de correo no deseado.', 'Mensaje');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cred = uifigure('Visible', 'off');
            app.cred.Name = 'Iniciar sesión';
            app.cred.CloseRequestFcn = createCallbackFcn(app, @credCloseRequest, true);
            app.ConectarButton = uibutton(app.cred, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.UsuarioEditFieldLabel = uilabel(app.cred);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.cred, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.cred);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.cred, 'text');
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.IntroduzcasuscredencialesdeaccesoLabel = uilabel(app.cred);
            app.IntroduzcasuscredencialesdeaccesoLabel.Text = 'Introduzca sus credenciales de acceso.';
            app.DebugEditFieldLabel = uilabel(app.cred);
            app.DebugEditFieldLabel.Visible = 'off';
            app.DebugEditFieldLabel.Text = 'Debug';
            app.DebugEditField = uieditfield(app.cred, 'text');
            app.DebugEditField.Editable = 'off';
            app.DebugEditField.Enable = 'off';
            app.DebugEditField.Visible = 'off';
            app.Hyperlink = uihyperlink(app.cred);
            app.Hyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @HyperlinkClicked, true);
            app.Hyperlink.Text = 'Recuperar contraseña';
            app.CorreoEditFieldLabel = uilabel(app.cred);
            app.CorreoEditFieldLabel.Enable = 'off';
            app.CorreoEditFieldLabel.Visible = 'off';
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.cred, 'text');
            app.CorreoEditField.Enable = 'off';
            app.CorreoEditField.Visible = 'off';
            app.RecuperarcontraseaLabel = uilabel(app.cred);
            app.RecuperarcontraseaLabel.Enable = 'off';
            app.RecuperarcontraseaLabel.Visible = 'off';
            app.RecuperarcontraseaLabel.Text = 'Recuperar contraseña:';
            app.Recuperar = uibutton(app.cred, 'push');
            app.Recuperar.ButtonPushedFcn = createCallbackFcn(app, @RecuperarButtonPushed, true);
            app.Recuperar.Icon = 'key.png';
            app.Recuperar.Enable = 'off';
            app.Recuperar.Visible = 'off';
            app.Recuperar.Text = '';
            % Show the figure after all components are created
            app.cred.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = credenciales(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cred)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cred)
        end
    end
end