classdef generacionalgoritmo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClasificadordeanotacionesUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CargarSealesTab                 matlab.ui.container.Tab
        TextoTiempoInterictal           matlab.ui.control.Label
        TextoDimensionesInterictal      matlab.ui.control.Label
        TextoTiempoPreictal             matlab.ui.control.Label
        TextoDimensionesPreictal        matlab.ui.control.Label
        TextoTiempoSano                 matlab.ui.control.Label
        TextoDimensionesSano            matlab.ui.control.Label
        ClasificacinarealizarButtonGroup  matlab.ui.container.ButtonGroup
        SanoIctalPreictalButton         matlab.ui.control.RadioButton
        SanoIctalButton                 matlab.ui.control.RadioButton
        CompletaButton                  matlab.ui.control.RadioButton
        TextoTiempoIctal                matlab.ui.control.Label
        TextoDimensionesIctal           matlab.ui.control.Label
        CargarMATIcterictalButton       matlab.ui.control.Button
        CargarEDFInterictalButton       matlab.ui.control.Button
        SealEEGInterictalLabel          matlab.ui.control.Label
        CargarMATPreictalButton         matlab.ui.control.Button
        CargarEDFPreictalButton         matlab.ui.control.Button
        SealEEGPreictalLabel            matlab.ui.control.Label
        CargarMATSanoButton             matlab.ui.control.Button
        CargarEDFSanoButton             matlab.ui.control.Button
        SealEEGSanoLabel                matlab.ui.control.Label
        CargarMATIctalButton            matlab.ui.control.Button
        SealEEGIctalLabel               matlab.ui.control.Label
        FreqMuestreoFsInterictalEditField  matlab.ui.control.NumericEditField
        FreqMuestreoFsEditField_4Label  matlab.ui.control.Label
        HzInterictal                    matlab.ui.control.Label
        FreqMuestreoFsSanoEditField     matlab.ui.control.NumericEditField
        FreqMuestreoFsEditField_3Label  matlab.ui.control.Label
        HzSano                          matlab.ui.control.Label
        FreqMuestreoFsPreictalEditField  matlab.ui.control.NumericEditField
        FreqMuestreoFsEditField_2Label  matlab.ui.control.Label
        HzPreictal                      matlab.ui.control.Label
        CARGADELASSEALESPARAELENTRENAMIENTOLabel  matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        FreqMuestreoFsIctalEditField    matlab.ui.control.NumericEditField
        FreqMuestreoFsEditFieldLabel    matlab.ui.control.Label
        HzIctal                         matlab.ui.control.Label
        CargarEDFIctalButton            matlab.ui.control.Button
        ExtraccinCaractersticasTab      matlab.ui.container.Tab
        CARACTERSTICASDEWAVELETLabel    matlab.ui.control.Label
        CARACTERSTICASDETIEMPOCONTNUOLabel  matlab.ui.control.Label
        NiveldedescomposicinDropDown    matlab.ui.control.DropDown
        NiveldedescomposicinLabel       matlab.ui.control.Label
        CaractersticasInterictalesExtradasLabel  matlab.ui.control.Label
        CaractersticasPreictalesExtradasLabel  matlab.ui.control.Label
        CaractersticasSanasExtradasLabel  matlab.ui.control.Label
        CaractersticasIctalesExtradasLabel  matlab.ui.control.Label
        CaractersticasInterictalesExtradasLamp  matlab.ui.control.Lamp
        CaractersticasPreictalesExtradasLamp  matlab.ui.control.Lamp
        CaractersticasSanasExtradasLamp  matlab.ui.control.Lamp
        CaractersticasIctalesExtradasLamp  matlab.ui.control.Lamp
        WaveletMadreDropDown            matlab.ui.control.DropDown
        WaveletMadreDropDownLabel       matlab.ui.control.Label
        EXTRACCINDELASCARACTERSTICASDELASSEALESLabel  matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel_2  matlab.ui.control.Label
        CanaldelEEGInterictalaanalizarDropDown  matlab.ui.control.DropDown
        CanaldelEEGInterictalaanalizarLabel  matlab.ui.control.Label
        CanaldelEEGPreictalaanalizarDropDown  matlab.ui.control.DropDown
        CanaldelEEGPreictalaanalizarLabel  matlab.ui.control.Label
        CanaldelEEGSanoaanalizarDropDown  matlab.ui.control.DropDown
        CanaldelEEGSanoaanalizarLabel   matlab.ui.control.Label
        TiempodeventanaEditField        matlab.ui.control.NumericEditField
        TiempodeventanaEditFieldLabel   matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        CanaldelEEGIctalaanalizarDropDown  matlab.ui.control.DropDown
        CanaldelEEGIctalaanalizarLabel  matlab.ui.control.Label
        GenerarCaractersticasButton     matlab.ui.control.StateButton
        TCEnergaAcumuladaCheckBox       matlab.ui.control.CheckBox
        WavCrucesporceroZCCheckBox      matlab.ui.control.CheckBox
        WavMediaCheckBox                matlab.ui.control.CheckBox
        WavDesviacinCheckBox            matlab.ui.control.CheckBox
        WavAsimetraCheckBox             matlab.ui.control.CheckBox
        WavCurtosisCheckBox             matlab.ui.control.CheckBox
        WavPotenciaCheckBox             matlab.ui.control.CheckBox
        TCValorMedioAbsolutoMAVCheckBox  matlab.ui.control.CheckBox
        TCCurtosisCheckBox              matlab.ui.control.CheckBox
        TCCrucesporceroZCCheckBox       matlab.ui.control.CheckBox
        TCDesviacinEstndarCheckBox      matlab.ui.control.CheckBox
        CaractersticasButtonGroup       matlab.ui.container.ButtonGroup
        WaveletButton                   matlab.ui.control.ToggleButton
        TiempoContinuoButton            matlab.ui.control.ToggleButton
        EntrenamientoClasificadorTab    matlab.ui.container.Tab
        GuardarclasificadorButton       matlab.ui.control.Button
        ExactituddelclasificadorLabel   matlab.ui.control.Label
        EntrenarelclasificadorespecificadoButton  matlab.ui.control.Button
        PARMETROSPARAUNCLASIFICADORSVMLabel  matlab.ui.control.Label
        KernelaemplearDropDown          matlab.ui.control.DropDown
        KernelaemplearLabel             matlab.ui.control.Label
        NmerodeParticionesKSpinner      matlab.ui.control.Spinner
        NmerodeParticionesKSpinnerLabel  matlab.ui.control.Label
        DivisindedatosporValidacinCruzadaLabel  matlab.ui.control.Label
        PARMETROSPARAUNCLASIFICADORRNALabel  matlab.ui.control.Label
        TextoPorcentaje3                matlab.ui.control.Label
        TextoPorcentaje2                matlab.ui.control.Label
        TextoPorcentaje                 matlab.ui.control.Label
        SegmentosPrueba                 matlab.ui.control.NumericEditField
        SegmentosdelasealparaelsetdepruebaEditFieldLabel  matlab.ui.control.Label
        SegmentosValidacion             matlab.ui.control.NumericEditField
        SegmentosdelasealparaelsetdevalidacinEditFieldLabel  matlab.ui.control.Label
        CapasOcultasDropDown            matlab.ui.control.DropDown
        CapasOcultasDropDownLabel       matlab.ui.control.Label
        AlgoritmoparaelentrenamientoDropDown  matlab.ui.control.DropDown
        AlgoritmoparaelentrenamientoDropDownLabel  matlab.ui.control.Label
        SegmentosEntrenamiento          matlab.ui.control.NumericEditField
        SegmentosdelasealparaelsetdeentrenamientoLabel  matlab.ui.control.Label
        CargarMATCaractersticasButton   matlab.ui.control.StateButton
        SELECCINYENTRENAMIENTODELCLASIFICADORLabel  matlab.ui.control.Label
        EPILEPTICEEGANALYSISTOOLBOXLabel_3  matlab.ui.control.Label
        ClasificadorButtonGroup         matlab.ui.container.ButtonGroup
        SVMButton                       matlab.ui.control.ToggleButton
        RedNeuronalButton               matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        CallingApp
        %Variables compartidas entre ventanas  
        s_ventana %Ventana de tiempo
        canales %Rango de canales para la extracción, en esta versión SÓLO se puede 1 canal
        
        %Canal a emplear y muestras por ventana Ictal
        cIctal
        muestrasIctal
        %Canal a emplear y muestras por ventana Sano
        cSano
        muestrasSano
        %Canal a emplear y muestras por ventana Preictal
        cPreictal
        muestrasPreictal
        %Canal a emplear y muestras por ventana Interictal
        cInterictal
        muestrasInterictal
        
        op %Vector de opciones
        Matriz_featuresIctal
        channel_ventanaIctal
        Matriz_featuresSano
        channel_ventanaSano
        Matriz_featuresPreictal
        channel_ventanaPreictal
        Matriz_featuresInterictal
        channel_ventanaInterictal
        
        %Wavelet madre y niveles de descomposición
        madre
        n
        
        %Structs para emplear sin cargar (?
        
        %Struct recuperado al cargar el .MAT
        Estructura_Caracteristicas
        
        %Algoritmo para guardar en el struct del clasificador         
        AlgorimoAprendizajeA
        
        Matriz_features_wavelet
        coeficientes_wavelet
        label
        featureop
        labelwave
        Wavelet_data
        archivo
        
        
        %Variables para la carga de archivo EEG Ictal
        datosIctal
        headerIctal
        FsIctal
        %Variables para la carga de archivo EEG Sano
        datosSano
        headerSano
        FsSano        
        %Variables para la carga de archivo EEG Preictal
        datosPreictal
        headerPreictal
        FsPreictal
        %Variables para la carga de archivo EEG Interictal
        datosInterictal
        headerInterictal
        FsInterictal
        
        %Tipo de clasificación (2, 3 o 4 clases)
        ClassType = 4;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.ClasificadordeanotacionesUIFigure,"center");
            app.CallingApp = mainapp; 
           %app.CaractersticasIctalesExtradasLabel.Text = num2str(app.ClassType);
            app.CaractersticasIctalesExtradasLamp.Visible = 'off';
            app.CaractersticasIctalesExtradasLabel.Visible = 'off';
            app.CaractersticasSanasExtradasLamp.Visible = 'off';
            app.CaractersticasSanasExtradasLabel.Visible = 'off';
            app.CaractersticasPreictalesExtradasLamp.Visible = 'off';
            app.CaractersticasPreictalesExtradasLabel.Visible = 'off';
            app.CaractersticasInterictalesExtradasLamp.Visible = 'off';
            app.CaractersticasInterictalesExtradasLabel.Visible = 'off';           
        end

        % Button pushed function: CargarEDFIctalButton
        function CargarEDFIctalButtonPushed(app, event)
            %Abrir archivo EDF ICTAL
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                 disp('Usuario canceló la operacion');
            else
                 disp(['EL usuario seleccionó ', fullfile(path,file)]);
                [hdr, record] = edfread(strcat(path,file));
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.datosIctal = record;
                app.headerIctal = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsIctal = hdr.frequency(1);
                app.FreqMuestreoFsIctalEditField.Value = app.FsIctal;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosIctal);
                numero_canalesIctal = min(datasize);
                DropDownOptionsIctal  = num2cell(zeros(1,numero_canalesIctal));
                for i=1:numero_canalesIctal
                    DropDownOptionsIctal{i} = num2str(i);
                end
                app.CanaldelEEGIctalaanalizarDropDown.Items = DropDownOptionsIctal;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesIctal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                app.TextoTiempoIctal.Text = append("Duración: ",num2str(hdr.records),"s");
            end
        end

        % Value changed function: GenerarCaractersticasButton
        function GenerarCaractersticasButtonValueChanged(app, event)

            %Mensaje de advertencia 
            message = sprintf('Esta operación puede tomar algunos minutos');
            uialert(app.ClasificadordeanotacionesUIFigure,message,'Aviso',...
            'Icon','warning');
            %Se visibilizan las lamparas indicadoras de progreso            
            app.CaractersticasIctalesExtradasLamp.Visible = 'on';
            app.CaractersticasIctalesExtradasLabel.Visible = 'on';
            app.CaractersticasSanasExtradasLamp.Visible = 'on';
            app.CaractersticasSanasExtradasLabel.Visible = 'on';
            app.CaractersticasPreictalesExtradasLamp.Visible = 'on';
            app.CaractersticasPreictalesExtradasLabel.Visible = 'on';
            app.CaractersticasInterictalesExtradasLamp.Visible = 'on';
            app.CaractersticasInterictalesExtradasLabel.Visible = 'on';
            app.CaractersticasIctalesExtradasLamp.Color = [1 0 0]; 
            app.CaractersticasSanasExtradasLamp.Color = [1 0 0]; 
            app.CaractersticasPreictalesExtradasLamp.Color = [1 0 0]; 
            app.CaractersticasInterictalesExtradasLamp.Color = [1 0 0]; 
            %Canal Ictal a utilizar
            app.cIctal = str2double(app.CanaldelEEGIctalaanalizarDropDown.Value);
            %Canal Sano a utilizar
            app.cSano = str2double(app.CanaldelEEGSanoaanalizarDropDown.Value);
            %Canal Preictal a utilizar
            app.cPreictal = str2double(app.CanaldelEEGPreictalaanalizarDropDown.Value);
            %Canal Interictal a utilizar
            app.cInterictal = str2double(app.CanaldelEEGInterictalaanalizarDropDown.Value);
            app.canales = 1; %Por el momento, sólo se usa 1 canal de cada señal                      

            
%           a = 1;
%           app.c=str2double(strsplit(a,{' ',','}));
            Features_result = struct();
            app.Matriz_featuresIctal = 0;
            app.channel_ventanaIctal = 0;
            app.Matriz_featuresSano = 0;
            app.channel_ventanaSano = 0;
            app.Matriz_featuresPreictal = 0;
            app.channel_ventanaPreictal = 0;
            app.Matriz_featuresInterictal = 0;
            app.channel_ventanaInterictal = 0;
            %Se obtiene la cantidad de segundos por ventana a emplear
            app.s_ventana = app.TiempodeventanaEditField.Value;
            %Se establece la Wavelet madre 
            app.madre = app.WaveletMadreDropDown.Value;
            %Se establecen los niveles de descomposición
            app.n = str2double(app.NiveldedescomposicinDropDown.Value);
            %Se elige si es analisis de TC o WAV
            app.op =[0,0,0,0,0,0];
            
            if(app.WaveletButton.Value==1)
                sel1='';sel2='';sel3='';sel4='';sel5='';sel6='';
                if app.WavDesviacinCheckBox.Value 
                    app.op(3)=1;
                    sel1 = 'DesviaciónEstándar';
                end
                if app.WavAsimetraCheckBox.Value
                    app.op(5) =1;
                    sel2 = 'Asimetría';
                end
                if app.WavMediaCheckBox.Value
                    app.op(2)=1;
                    sel3 = 'Media';
                end
                if app.WavPotenciaCheckBox.Value
                    app.op(1)=1;
                    sel4 = 'Potencia';
                end
                if app.WavCurtosisCheckBox.Value
                    app.op(4)=1;  
                    sel5 = 'Curtosis';
                end
                if app.WavCrucesporceroZCCheckBox.Value
                    app.op(6) =1;
                    sel6 = 'CrucesPorCero';
                end
            else                
                seleccion1 = '';seleccion2 = '';seleccion3 = '';seleccion4 = '';seleccion5 = '';
                if app.TCDesviacinEstndarCheckBox.Value
                    app.op(1) =1;
                    seleccion1= 'DesviaciónEstándar'; 
                end
                if app.TCCurtosisCheckBox.Value
                    app.op(2) =1;
                    seleccion2= 'Curtosis'; 
                end
                if app.TCCrucesporceroZCCheckBox.Value
                    app.op(3) =1;
                    seleccion3= 'CrucesPorCero'; 
                end
                if app.TCValorMedioAbsolutoMAVCheckBox.Value
                    app.op(4) =1;
                    seleccion4= 'ValorMedioAbsoluto'; 
                end
                if app.TCEnergaAcumuladaCheckBox.Value
                    app.op(5) =1;
                    seleccion5= 'EnergíaAcumulada'; 
                end       
            end
            
            %Se fijan las muestras por ventana para cada señal a emplear 
            %Si la Fs es decimal, se aproxima al entero más bajo    
            app.muestrasIctal =round(app.s_ventana*app.FsIctal);
            if((app.s_ventana*app.FsIctal-app.muestrasIctal)<0)
                app.muestrasIctal = app.muestrasIctal-1;
            end
            
            app.muestrasSano =round(app.s_ventana*app.FsSano);
            if((app.s_ventana*app.FsSano-app.muestrasSano)<0)
                app.muestrasSano = app.muestrasSano-1;
            end
            
            if(app.ClassType == 3)
                app.muestrasPreictal =round(app.s_ventana*app.FsPreictal);
                if((app.s_ventana*app.FsPreictal-app.muestrasPreictal)<0)
                    app.muestrasPreictal = app.muestrasPreictal-1;
                end
            elseif(app.ClassType == 4)
                app.muestrasPreictal =round(app.s_ventana*app.FsPreictal);
                if((app.s_ventana*app.FsPreictal-app.muestrasPreictal)<0)
                    app.muestrasPreictal = app.muestrasPreictal-1;
                end
                
                app.muestrasInterictal =round(app.s_ventana*app.FsInterictal);
                if((app.s_ventana*app.FsInterictal-app.muestrasInterictal)<0)
                    app.muestrasInterictal = app.muestrasInterictal-1;
                end
            end
            %Calcular caracteristicas seleccionadas (ver funcion features)
            
            if(app.TiempoContinuoButton.Value == 1) %Características en tiempo contínuo
                [vectorIctal,ventanadatosIctal,~]=FeaturesV2(app.datosIctal,app.FsIctal,app.canales,app.muestrasIctal,app.cIctal,app.op);
                app.Matriz_featuresIctal = vectorIctal;
                app.channel_ventanaIctal = ventanadatosIctal;
                Features_result.CarIctal.VectorCaracteristicasIctal = vectorIctal;
                Features_result.CarIctal.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                Features_result.CarIctal.canales = app.cIctal;
                Features_result.CarIctal.data = ventanadatosIctal;
                Features_result.CarIctal.FMuestreo = app.FsIctal;
                Features_result.CarIctal.Ventana = app.muestrasIctal;
                Features_result.CarIctal.EtiquetasIctal = ones(length(vectorIctal),1);
                app.CaractersticasIctalesExtradasLamp.Color = [0 1 0];            
                
                [vectorSano,ventanadatosSano,~]=FeaturesV2(app.datosSano,app.FsSano,app.canales,app.muestrasSano,app.cSano,app.op);
                app.Matriz_featuresSano = vectorSano;
                app.channel_ventanaSano = ventanadatosSano;
                Features_result.CarSano.VectorCaracteristicasSano = vectorSano;
                Features_result.CarSano.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                Features_result.CarSano.canales = app.cSano;
                Features_result.CarSano.data = ventanadatosSano;
                Features_result.CarSano.FMuestreo = app.FsSano;
                Features_result.CarSano.Ventana = app.muestrasSano;
                Features_result.CarSano.EtiquetasSano = 2*ones(length(vectorSano),1);
                app.CaractersticasSanasExtradasLamp.Color = [0 1 0];            
                if(app.ClassType == 4) %4 Clases
                    [vectorPreictal,ventanadatosPreictal,~]=FeaturesV2(app.datosPreictal,app.FsPreictal,app.canales,app.muestrasPreictal,app.cPreictal,app.op);
                    app.Matriz_featuresPreictal = vectorPreictal;
                    app.channel_ventanaPreictal = ventanadatosPreictal;
                    Features_result.CarPreictal.VectorCaracteristicasPreictal = vectorPreictal;
                    Features_result.CarPreictal.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.CarPreictal.canales = app.cPreictal;
                    Features_result.CarPreictal.data = ventanadatosPreictal;
                    Features_result.CarPreictal.FMuestreo = app.FsPreictal;
                    Features_result.CarPreictal.Ventana = app.muestrasPreictal;
                    Features_result.CarPreictal.EtiquetasPreictal = 3*ones(length(vectorPreictal),1);
                    app.CaractersticasPreictalesExtradasLamp.Color = [0 1 0];
                    
                    [vectorInterictal,ventanadatosInterictal,~]=FeaturesV2(app.datosInterictal,app.FsInterictal,app.canales,app.muestrasInterictal,app.cInterictal,app.op);
                    app.Matriz_featuresInterictal = vectorInterictal;
                    app.channel_ventanaInterictal = ventanadatosInterictal;
                    Features_result.CarInterictal.VectorCaracteristicasInterictal = vectorInterictal;
                    Features_result.CarInterictal.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.CarInterictal.canales = app.cInterictal;
                    Features_result.CarInterictal.data = ventanadatosInterictal;
                    Features_result.CarInterictal.FMuestreo = app.FsInterictal;
                    Features_result.CarInterictal.Ventana = app.muestrasInterictal;
                    Features_result.CarInterictal.EtiquetasInterictal = 4*ones(length(vectorInterictal),1);
                    app.CaractersticasInterictalesExtradasLamp.Color = [0 1 0];
                elseif(app.ClassType == 3) %3 Clases
                    [vectorPreictal,ventanadatosPreictal,~]=FeaturesV2(app.datosPreictal,app.FsPreictal,app.canales,app.muestrasPreictal,app.cPreictal,app.op);
                    app.Matriz_featuresPreictal = vectorPreictal;
                    app.channel_ventanaPreictal = ventanadatosPreictal;
                    Features_result.CarPreictal.VectorCaracteristicasPreictal = vectorPreictal;
                    Features_result.CarPreictal.Caracteristicas = [seleccion1,',',seleccion2,',',seleccion3,',',seleccion4,',',seleccion5];
                    Features_result.CarPreictal.canales = app.cPreictal;
                    Features_result.CarPreictal.data = ventanadatosPreictal;
                    Features_result.CarPreictal.FMuestreo = app.FsPreictal;
                    Features_result.CarPreictal.Ventana = app.muestrasPreictal;
                    Features_result.CarPreictal.EtiquetasPreictal = 3*ones(length(vectorPreictal),1);
                    app.CaractersticasPreictalesExtradasLamp.Color = [0 1 0];                   
                end
                Features_result.Tipo_Clasificacion = app.ClassType;
                Features_result.Vector_Caracteristicas = app.op;
                Features_result.Tipo_Caracteristicas = 0;
            else %Características Wavelet
                [vectorIctal,~,coeffIctal,~] = FeaturesV2wavelet(app.datosIctal,app.muestrasIctal,app.cIctal,app.madre,app.n,app.op);
                app.Matriz_featuresIctal = vectorIctal;
                Features_result.CarIctal.VectorCaracteristicasIctal = vectorIctal;
                Features_result.CarIctal.Caracteristicas = [sel1,',',sel2,',',sel3,',',sel4,',',sel5,',',sel6];
                Features_result.CarIctal.Canal = app.cIctal;
                Features_result.CarIctal.CoeficientesWavelet = coeffIctal;
                Features_result.CarIctal.FMuestreo = app.FsIctal;
                Features_result.CarIctal.Ventana = app.muestrasIctal;                
                Features_result.CarIctal.EtiquetasIctal = ones(length(vectorIctal),1);                                
                app.CaractersticasIctalesExtradasLamp.Color = [0 1 0];
                
                [vectorSano,~,coeffSano,~] = FeaturesV2wavelet(app.datosSano,app.muestrasSano,app.cSano,app.madre,app.n,app.op);
                app.Matriz_featuresSano = vectorSano;
                Features_result.CarSano.VectorCaracteristicasSano = vectorSano;
                Features_result.CarSano.Caracteristicas = [sel1,',',sel2,',',sel3,',',sel4,',',sel5,',',sel6];
                Features_result.CarSano.Canal = app.cSano;
                Features_result.CarSano.CoeficientesWavelet = coeffSano;
                Features_result.CarSano.FMuestreo = app.FsSano;
                Features_result.CarSano.Ventana = app.muestrasSano;                
                Features_result.CarSano.EtiquetasSano = 2*ones(length(vectorSano),1);                
                app.CaractersticasSanasExtradasLamp.Color = [0 1 0];
                
                if(app.ClassType == 4) %4 Clases
                    [vectorPreictal,~,coeffPreictal,~] = FeaturesV2wavelet(app.datosPreictal,app.muestrasPreictal,app.cPreictal,app.madre,app.n,app.op);
                    app.Matriz_featuresPreictal = vectorPreictal;
                    Features_result.CarPreictal.VectorCaracteristicasPreictal = vectorPreictal;
                    Features_result.CarPreictal.Caracteristicas = [sel1,',',sel2,',',sel3,',',sel4,',',sel5,',',sel6];
                    Features_result.CarPreictal.Canal = app.cPreictal;
                    Features_result.CarPreictal.CoeficientesWavelet = coeffPreictal;
                    Features_result.CarPreictal.FMuestreo = app.FsPreictal;
                    Features_result.CarPreictal.Ventana = app.muestrasPreictal;                
                    Features_result.CarPreictal.EtiquetasPreictal = 3*ones(length(vectorPreictal),1); 
                    app.CaractersticasPreictalesExtradasLamp.Color = [0 1 0];
                    
                    [vectorInterictal,~,coeffInterictal,~] = FeaturesV2wavelet(app.datosInterictal,app.muestrasInterictal,app.cInterictal,app.madre,app.n,app.op);
                    app.Matriz_featuresInterictal = vectorInterictal;
                    Features_result.CarInterictal.VectorCaracteristicasInterictal = vectorInterictal;
                    Features_result.CarInterictal.Caracteristicas = [sel1,',',sel2,',',sel3,',',sel4,',',sel5,',',sel6];
                    Features_result.CarInterictal.Canal = app.cInterictal;
                    Features_result.CarInterictal.CoeficientesWavelet = coeffInterictal;
                    Features_result.CarInterictal.FMuestreo = app.FsInterictal;
                    Features_result.CarInterictal.Ventana = app.muestrasInterictal;                
                    Features_result.CarInterictal.EtiquetasInterictal = 4*ones(length(vectorInterictal),1); 
                    app.CaractersticasInterictalesExtradasLamp.Color = [0 1 0];
                elseif(app.ClassType == 3) %3 Clases
                    [vectorPreictal,~,coeffPreictal,~] = FeaturesV2wavelet(app.datosPreictal,app.muestrasPreictal,app.cPreictal,app.madre,app.n,app.op);
                    app.Matriz_featuresPreictal = vectorPreictal;
                    Features_result.CarPreictal.VectorCaracteristicasPreictal = vectorPreictal;
                    Features_result.CarPreictal.Caracteristicas = [sel1,',',sel2,',',sel3,',',sel4,',',sel5,',',sel6];
                    Features_result.CarPreictal.Canal = app.cPreictal;
                    Features_result.CarPreictal.CoeficientesWavelet = coeffPreictal;
                    Features_result.CarPreictal.FMuestreo = app.FsPreictal;
                    Features_result.CarPreictal.Ventana = app.muestrasPreictal;                
                    Features_result.CarPreictal.EtiquetasPreictal = 3*ones(length(vectorPreictal),1); 
                    app.CaractersticasPreictalesExtradasLamp.Color = [0 1 0];
                end
                Features_result.Tipo_Clasificacion = app.ClassType;                
                Features_result.Tipo_Caracteristicas = 1;
                Features_result.Vector_Caracteristicas = app.op;
                Features_result.OndaMadre = app.madre;
                Features_result.NivelDescomposicion = app.n;
            end
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('VectorFeatures.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Features_result')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.ClasificadordeanotacionesUIFigure,message,'Aviso',...
            'Icon','success');            
        end

        % Button pushed function: CargarMATIctalButton
        function CargarMATIctalButtonPushed(app, event)
            %Seleccionar archivo MAT ICTAL
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.datosIctal = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsIctal = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsIctalEditField.Value = app.FsIctal;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosIctal);
                numero_canalesIctal = min(datasize);
                DropDownOptionsIctal  = num2cell(zeros(1,numero_canalesIctal));
                for i=1:numero_canalesIctal
                    DropDownOptionsIctal{i} = num2str(i);
                end
                app.CanaldelEEGIctalaanalizarDropDown.Items = DropDownOptionsIctal;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesIctal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoIctal.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoIctal.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoIctal.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoIctal.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
            end            
        end

        % Selection changed function: CaractersticasButtonGroup
        function CaractersticasButtonGroupSelectionChanged(app, event)
            if (app.WaveletButton.Value==1)
                app.TCCrucesporceroZCCheckBox.Enable = 'off';
                app.TCCurtosisCheckBox.Enable = 'off';
                app.TCDesviacinEstndarCheckBox.Enable = 'off';
                app.TCEnergaAcumuladaCheckBox.Enable = 'off';
                app.TCValorMedioAbsolutoMAVCheckBox.Enable = 'off';
                
                app.WavAsimetraCheckBox.Enable = 'on';
                app.WavCrucesporceroZCCheckBox.Enable = 'on';
                app.WavCurtosisCheckBox.Enable = 'on';
                app.WavDesviacinCheckBox.Enable = 'on';
                app.WavMediaCheckBox.Enable = 'on';
                app.WavPotenciaCheckBox.Enable = 'on';
                app.WaveletMadreDropDown.Enable = 'on';
                app.NiveldedescomposicinDropDown.Enable = 'on';
            else
                app.WavAsimetraCheckBox.Enable = 'off';
                app.WavCrucesporceroZCCheckBox.Enable = 'off';
                app.WavCurtosisCheckBox.Enable = 'off';
                app.WavDesviacinCheckBox.Enable = 'off';
                app.WavMediaCheckBox.Enable = 'off';
                app.WavPotenciaCheckBox.Enable = 'off';
                app.WaveletMadreDropDown.Enable = 'off';
                app.NiveldedescomposicinDropDown.Enable = 'off';
                
                app.TCCrucesporceroZCCheckBox.Enable = 'on';
                app.TCCurtosisCheckBox.Enable = 'on';
                app.TCDesviacinEstndarCheckBox.Enable = 'on';
                app.TCEnergaAcumuladaCheckBox.Enable = 'on';
                app.TCValorMedioAbsolutoMAVCheckBox.Enable = 'on';
            end
        end

        % Selection changed function: ClasificacinarealizarButtonGroup
        function ClasificacinarealizarButtonGroupSelectionChanged(app, event)
            %Habilitar las opciones para cargar la señal en dependencia de
            %la selección
            if(app.CompletaButton.Value == 1)
                app.CargarEDFPreictalButton.Enable = 'on';
                app.CargarMATPreictalButton.Enable = 'on';
                app.FreqMuestreoFsPreictalEditField.Enable = 'on';
                app.CanaldelEEGPreictalaanalizarDropDown.Enable = 'on';
                
                app.CargarEDFInterictalButton.Enable = 'on';
                app.CargarMATIcterictalButton.Enable = 'on';
                app.FreqMuestreoFsInterictalEditField.Enable = 'on';
                app.CanaldelEEGInterictalaanalizarDropDown.Enable = 'on';
                app.ClassType = 4;
            elseif(app.SanoIctalPreictalButton.Value == 1)
                app.FreqMuestreoFsPreictalEditField.Enable = 'on';
                app.CargarEDFPreictalButton.Enable = 'on';
                app.CargarMATPreictalButton.Enable = 'on';
                app.CanaldelEEGPreictalaanalizarDropDown.Enable = 'on';
                
                app.CargarEDFInterictalButton.Enable = 'off';
                app.CargarMATIcterictalButton.Enable = 'off';
                app.FreqMuestreoFsInterictalEditField.Enable = 'off';
                app.CanaldelEEGInterictalaanalizarDropDown.Enable = 'off';
                app.ClassType = 3;
            else
                app.FreqMuestreoFsPreictalEditField.Enable = 'off';
                app.CargarEDFPreictalButton.Enable = 'off';
                app.CargarMATPreictalButton.Enable = 'off';
                app.CanaldelEEGPreictalaanalizarDropDown.Enable = 'off';
                
                app.CargarEDFInterictalButton.Enable = 'off';
                app.CargarMATIcterictalButton.Enable = 'off';
                app.FreqMuestreoFsInterictalEditField.Enable = 'off';
                app.CanaldelEEGInterictalaanalizarDropDown.Enable = 'off';
                app.ClassType = 2;
            end            
        end

        % Button pushed function: CargarEDFSanoButton
        function CargarEDFSanoButtonPushed(app, event)
            %Abrir archivo EDF SANO
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                 disp('Usuario canceló la operacion');
            else
                 disp(['EL usuario seleccionó ', fullfile(path,file)]);
                [hdr, record] = edfread(strcat(path,file));
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.datosSano = record;
                app.headerSano = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsSano = hdr.frequency(1);
                app.FreqMuestreoFsSanoEditField.Value = app.FsSano;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosSano);
                numero_canalesSano = min(datasize);
                DropDownOptionsSano = num2cell(zeros(1,numero_canalesSano));
                for i=1:numero_canalesSano
                    DropDownOptionsSano{i} = num2str(i);
                end
                app.CanaldelEEGSanoaanalizarDropDown.Items = DropDownOptionsSano;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesSano.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                app.TextoTiempoSano.Text = append("Duración: ",num2str(hdr.records),"s");
            end
        end

        % Button pushed function: CargarMATSanoButton
        function CargarMATSanoButtonPushed(app, event)
            %Seleccionar archivo MAT SANO
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.datosSano = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsSano = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsSanoEditField.Value = app.FsSano;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosSano);
                numero_canalesSano = min(datasize);
                DropDownOptionsSano  = num2cell(zeros(1,numero_canalesSano));
                for i=1:numero_canalesSano
                    DropDownOptionsSano{i} = num2str(i);
                end
                app.CanaldelEEGSanoaanalizarDropDown.Items = DropDownOptionsSano;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesSano.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoSano.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoSano.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoSano.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoSano.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
            end                     
        end

        % Button pushed function: CargarEDFPreictalButton
        function CargarEDFPreictalButtonPushed(app, event)
            %Abrir archivo EDF PREICTAL
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                 disp('Usuario canceló la operacion');
            else
                 disp(['EL usuario seleccionó ', fullfile(path,file)]);
                [hdr, record] = edfread(strcat(path,file));
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.datosPreictal = record;
                app.headerPreictal = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsPreictal = hdr.frequency(1);
                app.FreqMuestreoFsPreictalEditField.Value = app.FsPreictal;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosPreictal);
                numero_canalesPreictal = min(datasize);
                DropDownOptionsPreictal  = num2cell(zeros(1,numero_canalesPreictal));
                for i=1:numero_canalesPreictal
                    DropDownOptionsPreictal{i} = num2str(i);
                end
                app.CanaldelEEGPreictalaanalizarDropDown.Items = DropDownOptionsPreictal;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesPreictal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                app.TextoTiempoPreictal.Text = append("Duración: ",num2str(hdr.records),"s");
            end            
        end

        % Button pushed function: CargarMATPreictalButton
        function CargarMATPreictalButtonPushed(app, event)
            %Seleccionar archivo MAT PREICTAL
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.datosPreictal = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsPreictal = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsPreictalEditField.Value = app.FsPreictal;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosPreictal);
                numero_canalesPreictal = min(datasize);
                DropDownOptionsPreictal  = num2cell(zeros(1,numero_canalesPreictal));
                for i=1:numero_canalesPreictal
                    DropDownOptionsPreictal{i} = num2str(i);
                end
                app.CanaldelEEGPreictalaanalizarDropDown.Items = DropDownOptionsPreictal;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesPreictal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoPreictal.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoPreictal.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoPreictal.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoPreictal.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
            end   
        end

        % Button pushed function: CargarEDFInterictalButton
        function CargarEDFInterictalButtonPushed(app, event)
            %Abrir archivo EDF INTERICTAL
            [file,path,~] = uigetfile({'*.edf'}, 'Seleccionar EDF');
            if isequal(file,0)
                 disp('Usuario canceló la operacion');
            else
                 disp(['EL usuario seleccionó ', fullfile(path,file)]);
                [hdr, record] = edfread(strcat(path,file));
                %Cargamos la señal EEG y su header a la variable de datos respectiva
                app.datosInterictal = record;
                app.headerInterictal = hdr;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsInterictal = hdr.frequency(1);
                app.FreqMuestreoFsInterictalEditField.Value = app.FsInterictal;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosInterictal);
                numero_canalesInterictal = min(datasize);
                DropDownOptionsInterictal  = num2cell(zeros(1,numero_canalesInterictal));
                for i=1:numero_canalesInterictal
                    DropDownOptionsInterictal{i} = num2str(i);
                end
                app.CanaldelEEGInterictalaanalizarDropDown.Items = DropDownOptionsInterictal;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesInterictal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                app.TextoTiempoInterictal.Text = append("Duración: ",num2str(hdr.records),"s");
            end         
        end

        % Button pushed function: CargarMATIcterictalButton
        function CargarMATIcterictalButtonPushed(app, event)
            %Seleccionar archivo MAT INTERICTAL
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar archivo M');
            if isequal(file,0)
                 disp('Usuario canceló operación');
            else
                 disp(['EL usuario selecccionó ', fullfile(path,file)]);
                %Asegurarse que el workspace cargado contenga la variable con
                %el nombre 'eeg_struct'
                workspace = load(strcat(path,file),'eeg_struct');
                %Cargamos la señal EEG a la variable de datos respectiva
                app.datosInterictal = workspace.eeg_struct.data;
                %Cargamos la frecuencia de muestreo de los datos, se muestra en
                %el label por si se necesita modificar
                app.FsInterictal = workspace.eeg_struct.sampling_frequency;
                app.FreqMuestreoFsInterictalEditField.Value = app.FsInterictal;
                %Se hace el cálculo para dejar que la persona seleccione 1 de
                %todos los canales que tiene
                datasize = size(app.datosInterictal);
                numero_canalesInterictal = min(datasize);
                DropDownOptionsInterictal  = num2cell(zeros(1,numero_canalesInterictal));
                for i=1:numero_canalesInterictal
                    DropDownOptionsInterictal{i} = num2str(i);
                end
                app.CanaldelEEGInterictalaanalizarDropDown.Items = DropDownOptionsInterictal;
                %Se indica en el texto derecho el tamaño y duración de los datos a emplear
                app.TextoDimensionesInterictal.Text = append("Tamaño: ",num2str(datasize(1))," x ",num2str(datasize(2)));
                segundos = workspace.eeg_struct.data_length_sec ;                               
                d = round((segundos/86400));
                if (d>segundos/86400)
                    d = d-1; 
                end
                s_temp = segundos-d*86400;
                h = round((s_temp/3600));
                if (h>s_temp/3600)
                    h = h-1; 
                end
                m =round(((s_temp/3600)-h)*60);
                if (m>((s_temp/3600)-h)*60)
                    m = m-1; 
                end
                s = s_temp-(h*3600)-m*60;
                if(d~=0)
                    app.TextoTiempoInterictal.Text = append("Duración: ",num2str(d)," Día, ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                else
                    if(h~=0)
                        app.TextoTiempoInterictal.Text = append("Duración: ",num2str(h)," h ",...
                                   num2str(m)," m ",num2str(s)," s");
                    else
                        if(m~=0)
                            app.TextoTiempoInterictal.Text = append("Duración: ",num2str(m)," m ",num2str(s)," s");
                        else
                            app.TextoTiempoInterictal.Text = append("Duración: ",num2str(s)," s");
                        end
                    end
                end
            end       
        end

        % Callback function
        function ButtonPushed(app, event)
            if (isempty(app.datosIctal)==1)
                app.Button.Text = 'Ictal Vacio';
            end
            if (isempty(app.datosIctal)==0)
                app.Button.Text = 'Ictal Lleno';
            end
        end

        % Selection changed function: ClasificadorButtonGroup
        function ClasificadorButtonGroupSelectionChanged(app, event)
            if (app.RedNeuronalButton.Value == 1)
                app.SegmentosEntrenamiento.Enable = 'on';
                app.SegmentosValidacion.Enable = 'on';
                app.SegmentosPrueba.Enable = 'on';
                app.CapasOcultasDropDown.Enable = 'on';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'on';
                
                app.NmerodeParticionesKSpinner.Enable = 'off';
                app.KernelaemplearDropDown.Enable = 'off';
            else
                app.SegmentosEntrenamiento.Enable = 'off';
                app.SegmentosValidacion.Enable = 'off';
                app.SegmentosPrueba.Enable = 'off';
                app.CapasOcultasDropDown.Enable = 'off';
                app.AlgoritmoparaelentrenamientoDropDown.Enable = 'off';
                
                app.NmerodeParticionesKSpinner.Enable = 'on';
                app.KernelaemplearDropDown.Enable = 'on';
            end
        end

        % Button pushed function: EntrenarelclasificadorespecificadoButton
        function EntrenarelclasificadorespecificadoButtonPushed(app, event)
            %Se verifica si se desean 2, 3 o 4 clases y el tipo de análisis
            %empleado para extraer las características
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
                        
            %Se cargan los vectores de caracteristicas correspondientes
            caracteristicas_Ictal = app.Estructura_Caracteristicas.CarIctal.VectorCaracteristicasIctal;            
            caracteristicas_Sano =  app.Estructura_Caracteristicas.CarSano.VectorCaracteristicasSano;
            
            %Homogeneización de las señales: Ya que puede que las señales
            %tengan distinta duración, puede que tengan una cantidad de
            %ventanas distintas entre ellas. Se acotará a que todas tengan
            %la misma cantidad que la que tenga menor número de ventanas
            cantidad_ventanas = [length(caracteristicas_Ictal),length(caracteristicas_Sano)];
            if (Tipo_class == 3)
                caracteristicas_Preictal = app.Estructura_Caracteristicas.CarPreictal.VectorCaracteristicasPreictal;    
                cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_Preictal)];
            elseif (Tipo_class == 4)
                caracteristicas_Preictal = app.Estructura_Caracteristicas.CarPreictal.VectorCaracteristicasPreictal;
                caracteristicas_Interictal = app.Estructura_Caracteristicas.CarInterictal.VectorCaracteristicasInterictal;
                cantidad_ventanas = [cantidad_ventanas,length(caracteristicas_Preictal),length(caracteristicas_Interictal)];
            end                       
            
            %Se encuentra cuál es la menor cantidad de ventanas entre los
            %vectores involucrados en el análisis, este es el máximo de
            %ventanas que pueden haber. Se acotan los vectores de car.
            cantidad_ventanasmaximas = min(cantidad_ventanas);
            if (Tipo_class ==2)
                caracteristicas_Ictal = caracteristicas_Ictal(1:cantidad_ventanasmaximas,:);
                caracteristicas_Sano = caracteristicas_Sano(1:cantidad_ventanasmaximas,:);
                %Unimos los vectores en una matriz con todas las
                %características
                matriz_completa_caracteristicas =[caracteristicas_Ictal',caracteristicas_Sano'];
            elseif (Tipo_class == 3)
                caracteristicas_Ictal = caracteristicas_Ictal(1:cantidad_ventanasmaximas,:);
                caracteristicas_Sano = caracteristicas_Sano(1:cantidad_ventanasmaximas,:);
                caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
                matriz_completa_caracteristicas =[caracteristicas_Ictal',caracteristicas_Sano',...
                                                  caracteristicas_Preictal'];
            elseif (Tipo_class == 4)
                caracteristicas_Ictal = caracteristicas_Ictal(1:cantidad_ventanasmaximas,:);
                caracteristicas_Sano = caracteristicas_Sano(1:cantidad_ventanasmaximas,:);
                caracteristicas_Preictal = caracteristicas_Preictal(1:cantidad_ventanasmaximas,:);
                caracteristicas_Interictal = caracteristicas_Interictal(1:cantidad_ventanasmaximas,:);
                matriz_completa_caracteristicas =[caracteristicas_Ictal',caracteristicas_Sano',...
                                                  caracteristicas_Preictal',caracteristicas_Interictal'];
            end            
            
            %Se crea y entrena el clasificador especificado
            if (app.RedNeuronalButton.Value == 1)                
                %Se emplea encoding One-Hot Para las etiquetas de la RN
                if (Tipo_class == 2)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                elseif (Tipo_class == 3)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                elseif (Tipo_class == 4)
                    vector_etiquetas_eeg =[ones(1,cantidad_ventanasmaximas),zeros(1,3*cantidad_ventanasmaximas);...
                                           zeros(1,cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,2*cantidad_ventanasmaximas);...
                                           zeros(1,2*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas),zeros(1,cantidad_ventanasmaximas);...
                                           zeros(1,3*cantidad_ventanasmaximas),ones(1,cantidad_ventanasmaximas)];
                end
                    
                %Se establecen los parámetros y se crea la red neuronal
                Func_entrenamiento = app.AlgoritmoparaelentrenamientoDropDown.Value;
                CapasOcultas = str2double(app.CapasOcultasDropDown.Value);
                net = patternnet(CapasOcultas, Func_entrenamiento);
                
                %Se entrena la red neuronal
                [net,tr] = train(net,matriz_completa_caracteristicas,vector_etiquetas_eeg);
                divitrain = app.SegmentosEntrenamiento.Value;
                divitest = app.SegmentosPrueba.Value;
                divival = app.SegmentosValidacion.Value;
                %Se revisa que el % con los tres grupos sea de 100%
                if divitrain+divitest+divival ~=100
                    divitrain= 70;
                    divitest = 15;
                    divival = 15;                    
                end
                
                net.divideParam.trainRatio = divitrain/100;
                net.divideParam.valRatio = divival/100;
                net.divideParam.testRatio = divitest/100;
                % Testear la red
                etiquetas_salida = net(matriz_completa_caracteristicas); 
                app.AlgorimoAprendizajeA = net;
                [~,cm] = confusion(vector_etiquetas_eeg,etiquetas_salida);
                if (Tipo_class == 2)
                    Ictal_11 = cm(1,1);%Ictal correcto
                    Ictal_12 = cm(1,2);%Ictal falso (sano)                   
                    
                    Sano_21 = cm(2,1);%Sano falso (ictal)
                    Sano_22 = cm(2,2);%Sano correcto
                    
                    accu = num2str(100*((Ictal_11+Sano_22) / (Ictal_11+Ictal_12+...
                                        Sano_21+Sano_22)));  %Exactitud
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'Ictal' 'Sano' 'Exactitud'})
                    set(gca,'yticklabel',{'Ictal' 'Sano' ''})                                        
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 3)
                    Ictal_11 = cm(1,1);%Ictal correcto
                    Ictal_12 = cm(1,2);%Ictal falso (sano)
                    Ictal_13 = cm(1,3); %Ictal falso (preict)                    
                    
                    Sano_21 = cm(2,1);%Sano falso (ictal)
                    Sano_22 = cm(2,2);%Sano correcto
                    Sano_23 = cm(2,3);%Sano falso (preict)
                    
                    Pre_31 = cm(3,1);%Preictal falso (ictal)
                    Pre_32 = cm(3,2);%Preictal falso (sano)
                    Pre_33 = cm(3,3);%Preictal correcto
                    
                    accu = num2str(100*((Ictal_11+Sano_22+Pre_33) / (Ictal_11+Ictal_12+Ictal_13+...
                                        Sano_21+Sano_22+Sano_23+...    
                                        Pre_31+Pre_32+Pre_33)));  %Exactitud
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'Ictal' 'Sano' 'Preictal' 'Exactitud'})
                    set(gca,'yticklabel',{'Ictal' 'Sano' 'Preictal' ''})
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 4)
                    Ictal_11 = cm(1,1);%Ictal correcto
                    Ictal_12 = cm(1,2);%Ictal falso (sano)
                    Ictal_13 = cm(1,3); %Ictal falso (preict)
                    Ictal_14 = cm(1,4);%Ictal falso (inter)
                    
                    Sano_21 = cm(2,1);%Sano falso (ictal)
                    Sano_22 = cm(2,2);%Sano correcto
                    Sano_23 = cm(2,3);%Sano falso (preict)
                    Sano_24 = cm(2,4);%Sano falso (inter)
                    
                    Pre_31 = cm(3,1);%Preictal falso (ictal)
                    Pre_32 = cm(3,2);%Preictal falso (sano)
                    Pre_33 = cm(3,3);%Preictal correcto
                    Pre_34 = cm(3,4);%Preictal falso (inter)
                    
                    Inter_41 = cm(4,1);%Interictal falso (ictal)
                    Inter_42 = cm(4,2);%Interictal falso (sano)
                    Inter_43 = cm(4,3);%Interictal falso (preict)
                    Inter_44 = cm(4,4);%Interictal correcto
                    
                    accu = num2str(100*((Ictal_11+Sano_22+Pre_33+Inter_44) / (Ictal_11+Ictal_12+Ictal_13+Ictal_14+...
                                        Sano_21+Sano_22+Sano_23+Sano_24+...    
                                        Pre_31+Pre_32+Pre_33+Pre_34+...
                                        Inter_41+Inter_42+Inter_43+Inter_44)));  %Exactitud
                    plotconfusion(vector_etiquetas_eeg,etiquetas_salida,{'Matriz de Confusión'});
                    set(gca,'xticklabel',{'Ictal' 'Sano' 'Preictal' 'Interictal' 'Exactitud'})
                    set(gca,'yticklabel',{'Ictal' 'Sano' 'Preictal' 'Interictal' ''})
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",accu,"%");
                end                    
            else
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.CarIctal.EtiquetasIctal(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.CarSano.EtiquetasSano(1:cantidad_ventanasmaximas,:)];
                elseif (Tipo_class == 3)
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.CarIctal.EtiquetasIctal(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.CarSano.EtiquetasSano(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:)];
                elseif (Tipo_class == 4)                   
                    vector_etiquetas_eeg = [app.Estructura_Caracteristicas.CarIctal.EtiquetasIctal(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.CarSano.EtiquetasSano(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.CarPreictal.EtiquetasPreictal(1:cantidad_ventanasmaximas,:);...
                                            app.Estructura_Caracteristicas.CarInterictal.EtiquetasInterictal(1:cantidad_ventanasmaximas,:)];
                end
                %Se transpone la matriz de características para este
                %clasificador (SVM)
                matriz_completa_caracteristicas = matriz_completa_caracteristicas';
                
                %Validacion cruzada para el entrenamiento del clasificador
                k = app.NmerodeParticionesKSpinner.Value;
                cvFolds = crossvalind('Kfold', vector_etiquetas_eeg, k);   
                cp = classperf(vector_etiquetas_eeg);        %# init performance tracker
                for i = 1:k                                  %# for each fold
                    testIdx = (cvFolds == i);                %# get indices of test instances
                    trainIdx = ~testIdx;
                    %Obtener conjuntos train y test
                    datos_entrenamiento = matriz_completa_caracteristicas(trainIdx,:);
                    etiquetas_entrenamiento = vector_etiquetas_eeg(trainIdx,:);
                    datos_prueba = matriz_completa_caracteristicas(testIdx,:);
                    etiquetas_prueba = vector_etiquetas_eeg(testIdx,:);                  
                end
                
                %Parámetros y entrenamiento de la SVM
                kernel = app.KernelaemplearDropDown.Value;
                t_SVM = templateSVM('Standardize',true,'KernelFunction',kernel);
                model_svm = fitcecoc(datos_entrenamiento,etiquetas_entrenamiento,'Learners',t_SVM,'FitPosterior',true,...
                    'Verbose',2);
                [labelPred,~] =  predict(model_svm, datos_prueba);
                etiquetas_generadas = labelPred;                
                Prueba_eti = categorical(etiquetas_prueba');
                Predict_eti = categorical(etiquetas_generadas');
                app.AlgorimoAprendizajeA = model_svm;
                %Graficamos la matriz de confusión
                plotconfusion(Prueba_eti,Predict_eti,{'Matriz de Confusión'});
                if (Tipo_class == 2)
                    %Se emplea un vector columna con los números 1 a 4 para las
                    %etiquetas de la SVM
                    set(gca,'xticklabel',{'Ictal' 'Sano' 'Exactitud'})
                    set(gca,'yticklabel',{'Ictal' 'Sano' ''})
                    %Calculamos la exactitud del clasificador
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2))/(s(1)+s(2));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 3)
                    set(gca,'xticklabel',{'Ictal' 'Sano' 'Preictal' 'Exactitud'})
                    set(gca,'yticklabel',{'Ictal' 'Sano' 'Preictal' ''})
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3))/(s(1)+s(2)+s(3));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                elseif (Tipo_class == 4)                   
                    set(gca,'xticklabel',{'Ictal' 'Sano' 'Preictal' 'Interictal' 'Exactitud'})
                    set(gca,'yticklabel',{'Ictal' 'Sano' 'Preictal' 'Interictal' ''})
                    matriz = confusionmat(Prueba_eti,Predict_eti);
                    s = sum(matriz);
                    accu = 100*(matriz(1,1)+matriz(2,2)+matriz(3,3)+matriz(4,4))/(s(1)+s(2)+s(3)+s(4));
                    app.ExactituddelclasificadorLabel.Text = append("Exactitud del clasificador: ",num2str(accu),"%");
                end                                
            end
        end

        % Value changed function: CargarMATCaractersticasButton
        function CargarMATCaractersticasButtonValueChanged(app, event)
            %Seleccionar archivo MAT con vector de caracteristicas y
            %etiquetas
            [file,path,~] = uigetfile({'*.mat'}, 'Seleccionar Variable Matlab');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            espacio_trabajo_temp = load(strcat(path,file),'Features_result');
            app.Estructura_Caracteristicas = espacio_trabajo_temp.Features_result;
        end

        % Button pushed function: GuardarclasificadorButton
        function GuardarclasificadorButtonPushed(app, event)
            Clasificador_Entrenado = struct();
            Tipo_class = app.Estructura_Caracteristicas.Tipo_Clasificacion;
            Tipo_car = app.Estructura_Caracteristicas.Tipo_Caracteristicas;
            %Se lee el vector de opciones, primero se descompone el texto            
            Cellop = strsplit(app.Estructura_Caracteristicas.CarIctal.Caracteristicas,',');
            Vop = app.Estructura_Caracteristicas.Vector_Caracteristicas;
            if Tipo_car == 1
                %CarWavelet
                Wmadre = app.Estructura_Caracteristicas.OndaMadre;
                Wn = app.Estructura_Caracteristicas.NivelDescomposicion;
            end                        
            
            Clasificador_Entrenado.Clasificador = app.AlgorimoAprendizajeA;
            if(app.RedNeuronalButton.Value ==1)                
                Clasificador_Entrenado.Tipo_Clasificador = 0;                
            else
                Clasificador_Entrenado.Tipo_Clasificador = 1;                
            end
            Clasificador_Entrenado.Caracteristicas.Tipo_Caracteristicas = Tipo_car;
            if (Tipo_car == 1)
                Clasificador_Entrenado.Caracteristicas.Madre = Wmadre;
                Clasificador_Entrenado.Caracteristicas.Descomp = Wn;
            end            
            Clasificador_Entrenado.Vector_op = Vop;
            Clasificador_Entrenado.No_Clases = Tipo_class;  
            
            %Guardar archivo MAT
            startingFolder = 'C:\Program Files\MATLAB'
            if ~exist(startingFolder, 'dir')
                startingFolder = pwd;
            end
            % Put in the name of the mat file that the user wants to save.
            % defaultFileName = fullfile(startingFolder, '*.mat')
            [file,path] = uiputfile('Clasificador.mat','Save file name');
            if file == 0
            % User clicked the Cancel button.
             return;
            end
            
            %AQUI ESTABA LO DEL STRUCT DE FEATURES ANTES
            
            %Guardar archivo en la ruta seleccionada
            fullFileName = fullfile(path, file);            
            save(fullFileName,'Clasificador_Entrenado')
            
            %Mensaje de exito 
            message = sprintf(['Archivo ',file,' guardado exitosamente']);
            uialert(app.ClasificadordeanotacionesUIFigure,message,'Aviso',...
            'Icon','success');  
        end

        % Value changed function: SegmentosEntrenamiento
        function SegmentosEntrenamientoValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar un 100 porciento');
                    uialert(app.ClasificadordeanotacionesUIFigure,message,'Aviso',...
                    'Icon','warning');                        
            end            
        end

        % Value changed function: SegmentosValidacion
        function SegmentosValidacionValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar un 100 porciento');
                    uialert(app.ClasificadordeanotacionesUIFigure,message,'Aviso',...
                    'Icon','warning');                        
            end  
        end

        % Value changed function: SegmentosPrueba
        function SegmentosPruebaValueChanged(app, event)
            divitrain = app.SegmentosEntrenamiento.Value;
            divitest = app.SegmentosPrueba.Value;
            divival = app.SegmentosValidacion.Value;
            if divitrain+divitest+divival ~=100
               %Mensaje de advertencia 
                message = sprintf('Los porcentajes deben sumar un 100 porciento');
                    uialert(app.ClasificadordeanotacionesUIFigure,message,'Aviso',...
                    'Icon','warning');                        
            end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ClasificadordeanotacionesUIFigure = uifigure('Visible', 'off');
            app.ClasificadordeanotacionesUIFigure.Name = 'Clasificador de anotaciones';
            app.ClasificadordeanotacionesUIFigure.Icon = fullfile(pathToMLAPP, 'icons_resources', 'icon_48.png');
            app.TabGroup = uitabgroup(app.ClasificadordeanotacionesUIFigure);
            app.CargarSealesTab = uitab(app.TabGroup);
            app.CargarSealesTab.Title = 'Cargar Señales';
            app.CargarEDFIctalButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarEDFIctalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFIctalButtonPushed, true);
            app.CargarEDFIctalButton.Text = 'Cargar EDF';
            app.HzIctal = uilabel(app.CargarSealesTab);
            app.HzIctal.Text = 'Hz';
            app.FreqMuestreoFsEditFieldLabel = uilabel(app.CargarSealesTab);
            app.FreqMuestreoFsEditFieldLabel.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsIctalEditField = uieditfield(app.CargarSealesTab, 'numeric');
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.CargarSealesTab);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.CARGADELASSEALESPARAELENTRENAMIENTOLabel = uilabel(app.CargarSealesTab);
            app.CARGADELASSEALESPARAELENTRENAMIENTOLabel.Text = 'CARGA DE LAS SEÑALES PARA EL ENTRENAMIENTO';
            app.HzPreictal = uilabel(app.CargarSealesTab);
            app.HzPreictal.Text = 'Hz';
            app.FreqMuestreoFsEditField_2Label = uilabel(app.CargarSealesTab);
            app.FreqMuestreoFsEditField_2Label.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsPreictalEditField = uieditfield(app.CargarSealesTab, 'numeric');
            app.HzSano = uilabel(app.CargarSealesTab);
            app.HzSano.Text = 'Hz';
            app.FreqMuestreoFsEditField_3Label = uilabel(app.CargarSealesTab);
            app.FreqMuestreoFsEditField_3Label.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsSanoEditField = uieditfield(app.CargarSealesTab, 'numeric');
            app.HzInterictal = uilabel(app.CargarSealesTab);
            app.HzInterictal.Text = 'Hz';
            app.FreqMuestreoFsEditField_4Label = uilabel(app.CargarSealesTab);
            app.FreqMuestreoFsEditField_4Label.Text = 'Freq. Muestreo (Fs)';
            app.FreqMuestreoFsInterictalEditField = uieditfield(app.CargarSealesTab, 'numeric');
            app.SealEEGIctalLabel = uilabel(app.CargarSealesTab);
            app.SealEEGIctalLabel.Text = 'Señal EEG Ictal';
            app.CargarMATIctalButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarMATIctalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATIctalButtonPushed, true);
            app.CargarMATIctalButton.Text = 'Cargar MAT';
            app.SealEEGSanoLabel = uilabel(app.CargarSealesTab);
            app.SealEEGSanoLabel.Text = 'Señal EEG Sano';
            app.CargarEDFSanoButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarEDFSanoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFSanoButtonPushed, true);
            app.CargarEDFSanoButton.Text = 'Cargar EDF';
            app.CargarMATSanoButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarMATSanoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATSanoButtonPushed, true);
            app.CargarMATSanoButton.Text = 'Cargar MAT';
            app.SealEEGPreictalLabel = uilabel(app.CargarSealesTab);
            app.SealEEGPreictalLabel.Text = 'Señal EEG Preictal';
            app.CargarEDFPreictalButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarEDFPreictalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFPreictalButtonPushed, true);
            app.CargarEDFPreictalButton.Text = 'Cargar EDF';
            app.CargarMATPreictalButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarMATPreictalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATPreictalButtonPushed, true);
            app.CargarMATPreictalButton.Text = 'Cargar MAT';
            app.SealEEGInterictalLabel = uilabel(app.CargarSealesTab);
            app.SealEEGInterictalLabel.Text = 'Señal EEG Interictal';
            app.CargarEDFInterictalButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarEDFInterictalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarEDFInterictalButtonPushed, true);
            app.CargarEDFInterictalButton.Text = 'Cargar EDF';
            app.CargarMATIcterictalButton = uibutton(app.CargarSealesTab, 'push');
            app.CargarMATIcterictalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarMATIcterictalButtonPushed, true);
            app.CargarMATIcterictalButton.Text = 'Cargar MAT';
            app.TextoDimensionesIctal = uilabel(app.CargarSealesTab);
            app.TextoDimensionesIctal.Text = 'Tamaño: - x -';
            app.TextoTiempoIctal = uilabel(app.CargarSealesTab);
            app.TextoTiempoIctal.Text = 'Duración: -';
            app.ClasificacinarealizarButtonGroup = uibuttongroup(app.CargarSealesTab);
            app.ClasificacinarealizarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClasificacinarealizarButtonGroupSelectionChanged, true);
            app.ClasificacinarealizarButtonGroup.Title = 'Clasificación a realizar';
            app.CompletaButton = uiradiobutton(app.ClasificacinarealizarButtonGroup);
            app.CompletaButton.Text = 'Completa';
            app.CompletaButton.Value = true;
            app.SanoIctalButton = uiradiobutton(app.ClasificacinarealizarButtonGroup);
            app.SanoIctalButton.Text = 'Sano/Ictal';
            app.SanoIctalPreictalButton = uiradiobutton(app.ClasificacinarealizarButtonGroup);
            app.SanoIctalPreictalButton.Text = 'Sano/Ictal/Preictal';
            app.TextoDimensionesSano = uilabel(app.CargarSealesTab);
            app.TextoDimensionesSano.Text = 'Tamaño: - x -';
            app.TextoTiempoSano = uilabel(app.CargarSealesTab);
            app.TextoTiempoSano.Text = 'Duración: -';
            app.TextoDimensionesPreictal = uilabel(app.CargarSealesTab);
            app.TextoDimensionesPreictal.Text = 'Tamaño: - x -';
            app.TextoTiempoPreictal = uilabel(app.CargarSealesTab);
            app.TextoTiempoPreictal.Text = 'Duración: -';
            app.TextoDimensionesInterictal = uilabel(app.CargarSealesTab);
            app.TextoDimensionesInterictal.Text = 'Tamaño: - x -';
            app.TextoTiempoInterictal = uilabel(app.CargarSealesTab);
            app.TextoTiempoInterictal.Text = 'Duración: -';
            app.ExtraccinCaractersticasTab = uitab(app.TabGroup);
            app.ExtraccinCaractersticasTab.Title = 'Extracción Características';
            app.CaractersticasButtonGroup = uibuttongroup(app.ExtraccinCaractersticasTab);
            app.CaractersticasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaractersticasButtonGroupSelectionChanged, true);
            app.CaractersticasButtonGroup.Title = 'Características';
            app.TiempoContinuoButton = uitogglebutton(app.CaractersticasButtonGroup);
            app.TiempoContinuoButton.Text = 'Tiempo Continuo';
            app.TiempoContinuoButton.Value = true;
            app.WaveletButton = uitogglebutton(app.CaractersticasButtonGroup);
            app.WaveletButton.Text = 'Wavelet';
            app.TCDesviacinEstndarCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.TCDesviacinEstndarCheckBox.Text = {'Desviación'; 'Estándar'};
            app.TCDesviacinEstndarCheckBox.Value = true;
            app.TCCrucesporceroZCCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.TCCrucesporceroZCCheckBox.Text = {'Cruces por'; 'cero (ZC)'};
            app.TCCurtosisCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.TCCurtosisCheckBox.Text = 'Curtosis';
            app.TCValorMedioAbsolutoMAVCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.TCValorMedioAbsolutoMAVCheckBox.Text = {'Valor Medio Absoluto'; ' (MAV)'};
            app.WavPotenciaCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.WavPotenciaCheckBox.Enable = 'off';
            app.WavPotenciaCheckBox.Text = 'Potencia';
            app.WavPotenciaCheckBox.Value = true;
            app.WavCurtosisCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.WavCurtosisCheckBox.Enable = 'off';
            app.WavCurtosisCheckBox.Text = 'Curtosis';
            app.WavAsimetraCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.WavAsimetraCheckBox.Enable = 'off';
            app.WavAsimetraCheckBox.Text = 'Asimetría';
            app.WavDesviacinCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.WavDesviacinCheckBox.Enable = 'off';
            app.WavDesviacinCheckBox.Text = 'Desviación';
            app.WavMediaCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.WavMediaCheckBox.Enable = 'off';
            app.WavMediaCheckBox.Text = 'Media';
            app.WavCrucesporceroZCCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.WavCrucesporceroZCCheckBox.Enable = 'off';
            app.WavCrucesporceroZCCheckBox.Text = {'Cruces por'; 'cero (ZC)'};
            app.TCEnergaAcumuladaCheckBox = uicheckbox(app.ExtraccinCaractersticasTab);
            app.TCEnergaAcumuladaCheckBox.Text = {'Energía'; 'Acumulada'};
            app.GenerarCaractersticasButton = uibutton(app.ExtraccinCaractersticasTab, 'state');
            app.GenerarCaractersticasButton.ValueChangedFcn = createCallbackFcn(app, @GenerarCaractersticasButtonValueChanged, true);
            app.GenerarCaractersticasButton.Text = 'Generar Características';
            app.CanaldelEEGIctalaanalizarLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGIctalaanalizarLabel.Text = {'Canal del EEG '; 'Ictal a analizar'};
            app.CanaldelEEGIctalaanalizarDropDown = uidropdown(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGIctalaanalizarDropDown.Items = {'-'};
            app.CanaldelEEGIctalaanalizarDropDown.Value = '-';
            app.sLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.sLabel.Enable = 'off';
            app.sLabel.Visible = 'off';
            app.sLabel.Text = 's';
            app.TiempodeventanaEditFieldLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.TiempodeventanaEditFieldLabel.Enable = 'off';
            app.TiempodeventanaEditFieldLabel.Visible = 'off';
            app.TiempodeventanaEditFieldLabel.Text = 'Tiempo de ventana';
            app.TiempodeventanaEditField = uieditfield(app.ExtraccinCaractersticasTab, 'numeric');
            app.TiempodeventanaEditField.Enable = 'off';
            app.TiempodeventanaEditField.Visible = 'off';
            app.TiempodeventanaEditField.Value = 1;
            app.CanaldelEEGSanoaanalizarLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGSanoaanalizarLabel.Text = {'Canal del EEG '; 'Sano a analizar'};
            app.CanaldelEEGSanoaanalizarDropDown = uidropdown(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGSanoaanalizarDropDown.Items = {'-'};
            app.CanaldelEEGSanoaanalizarDropDown.Value = '-';
            app.CanaldelEEGPreictalaanalizarLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGPreictalaanalizarLabel.Text = {'Canal del EEG '; 'Preictal a analizar'};
            app.CanaldelEEGPreictalaanalizarDropDown = uidropdown(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGPreictalaanalizarDropDown.Items = {'-'};
            app.CanaldelEEGPreictalaanalizarDropDown.Value = '-';
            app.CanaldelEEGInterictalaanalizarLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGInterictalaanalizarLabel.Text = {'Canal del EEG '; 'Interictal a analizar'};
            app.CanaldelEEGInterictalaanalizarDropDown = uidropdown(app.ExtraccinCaractersticasTab);
            app.CanaldelEEGInterictalaanalizarDropDown.Items = {'-'};
            app.CanaldelEEGInterictalaanalizarDropDown.Value = '-';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel_2 = uilabel(app.ExtraccinCaractersticasTab);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel_2.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.EXTRACCINDELASCARACTERSTICASDELASSEALESLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.EXTRACCINDELASCARACTERSTICASDELASSEALESLabel.Text = 'EXTRACCIÓN DE LAS CARACTERÍSTICAS DE LAS SEÑALES';
            app.WaveletMadreDropDownLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.WaveletMadreDropDownLabel.Enable = 'off';
            app.WaveletMadreDropDownLabel.Text = {'Wavelet'; 'Madre'};
            app.WaveletMadreDropDown = uidropdown(app.ExtraccinCaractersticasTab);
            app.WaveletMadreDropDown.Items = {'db3', 'db4', 'db5', 'db10'};
            app.WaveletMadreDropDown.Enable = 'off';
            app.WaveletMadreDropDown.Value = 'db3';
            app.CaractersticasIctalesExtradasLamp = uilamp(app.ExtraccinCaractersticasTab);
            app.CaractersticasIctalesExtradasLamp.Visible = 'off';
            app.CaractersticasSanasExtradasLamp = uilamp(app.ExtraccinCaractersticasTab);
            app.CaractersticasSanasExtradasLamp.Visible = 'off';
            app.CaractersticasPreictalesExtradasLamp = uilamp(app.ExtraccinCaractersticasTab);
            app.CaractersticasPreictalesExtradasLamp.Visible = 'off';
            app.CaractersticasInterictalesExtradasLamp = uilamp(app.ExtraccinCaractersticasTab);
            app.CaractersticasInterictalesExtradasLamp.Visible = 'off';
            app.CaractersticasIctalesExtradasLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CaractersticasIctalesExtradasLabel.Visible = 'off';
            app.CaractersticasIctalesExtradasLabel.Text = 'Características Ictales Extraídas';
            app.CaractersticasSanasExtradasLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CaractersticasSanasExtradasLabel.Visible = 'off';
            app.CaractersticasSanasExtradasLabel.Text = 'Características Sanas Extraídas';
            app.CaractersticasPreictalesExtradasLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CaractersticasPreictalesExtradasLabel.Visible = 'off';
            app.CaractersticasPreictalesExtradasLabel.Text = 'Características Preictales Extraídas';
            app.CaractersticasInterictalesExtradasLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CaractersticasInterictalesExtradasLabel.Visible = 'off';
            app.CaractersticasInterictalesExtradasLabel.Text = 'Características Interictales Extraídas';
            app.NiveldedescomposicinLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.NiveldedescomposicinLabel.Enable = 'off';
            app.NiveldedescomposicinLabel.Text = {'Nivel de'; 'descomposición'};
            app.NiveldedescomposicinDropDown = uidropdown(app.ExtraccinCaractersticasTab);
            app.NiveldedescomposicinDropDown.Items = {'2', '3', '4', '5', '6', '7'};
            app.NiveldedescomposicinDropDown.Enable = 'off';
            app.NiveldedescomposicinDropDown.Value = '2';
            app.CARACTERSTICASDETIEMPOCONTNUOLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CARACTERSTICASDETIEMPOCONTNUOLabel.Text = 'CARACTERÍSTICAS DE TIEMPO CONTÍNUO';
            app.CARACTERSTICASDEWAVELETLabel = uilabel(app.ExtraccinCaractersticasTab);
            app.CARACTERSTICASDEWAVELETLabel.Text = 'CARACTERÍSTICAS DE WAVELET';
            app.EntrenamientoClasificadorTab = uitab(app.TabGroup);
            app.EntrenamientoClasificadorTab.Title = 'Entrenamiento Clasificador';
            app.ClasificadorButtonGroup = uibuttongroup(app.EntrenamientoClasificadorTab);
            app.ClasificadorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClasificadorButtonGroupSelectionChanged, true);
            app.ClasificadorButtonGroup.Title = 'Clasificador';
            app.RedNeuronalButton = uitogglebutton(app.ClasificadorButtonGroup);
            app.RedNeuronalButton.Text = 'Red Neuronal';
            app.RedNeuronalButton.Value = true;
            app.SVMButton = uitogglebutton(app.ClasificadorButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel_3 = uilabel(app.EntrenamientoClasificadorTab);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel_3.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.SELECCINYENTRENAMIENTODELCLASIFICADORLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.SELECCINYENTRENAMIENTODELCLASIFICADORLabel.Text = 'SELECCIÓN Y ENTRENAMIENTO DEL CLASIFICADOR';
            app.CargarMATCaractersticasButton = uibutton(app.EntrenamientoClasificadorTab, 'state');
            app.CargarMATCaractersticasButton.ValueChangedFcn = createCallbackFcn(app, @CargarMATCaractersticasButtonValueChanged, true);
            app.CargarMATCaractersticasButton.Text = 'Cargar MAT Características';
            app.SegmentosdelasealparaelsetdeentrenamientoLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.SegmentosdelasealparaelsetdeentrenamientoLabel.Text = {'Segmentos de la señal para'; 'el set de entrenamiento'};
            app.SegmentosEntrenamiento = uieditfield(app.EntrenamientoClasificadorTab, 'numeric');
            app.SegmentosEntrenamiento.Limits = [0 90];
            app.SegmentosEntrenamiento.ValueChangedFcn = createCallbackFcn(app, @SegmentosEntrenamientoValueChanged, true);
            app.SegmentosEntrenamiento.Value = 70;
            app.AlgoritmoparaelentrenamientoDropDownLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.AlgoritmoparaelentrenamientoDropDownLabel.Text = {'Algoritmo para el'; 'entrenamiento'};
            app.AlgoritmoparaelentrenamientoDropDown = uidropdown(app.EntrenamientoClasificadorTab);
            app.AlgoritmoparaelentrenamientoDropDown.Items = {'trainscg', 'trainr', 'trainrp', 'traingd', '', '', ''};
            app.AlgoritmoparaelentrenamientoDropDown.Value = 'trainscg';
            app.CapasOcultasDropDownLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.CapasOcultasDropDownLabel.Text = {'Capas '; 'Ocultas'};
            app.CapasOcultasDropDown = uidropdown(app.EntrenamientoClasificadorTab);
            app.CapasOcultasDropDown.Items = {'8', '10', '12', '15', '', '', ''};
            app.CapasOcultasDropDown.Value = '8';
            app.SegmentosdelasealparaelsetdevalidacinEditFieldLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.SegmentosdelasealparaelsetdevalidacinEditFieldLabel.Text = {'Segmentos de la señal para'; 'el set de validación'};
            app.SegmentosValidacion = uieditfield(app.EntrenamientoClasificadorTab, 'numeric');
            app.SegmentosValidacion.Limits = [0 90];
            app.SegmentosValidacion.ValueChangedFcn = createCallbackFcn(app, @SegmentosValidacionValueChanged, true);
            app.SegmentosValidacion.Value = 15;
            app.SegmentosdelasealparaelsetdepruebaEditFieldLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.SegmentosdelasealparaelsetdepruebaEditFieldLabel.Text = {'Segmentos de la señal para'; 'el set de prueba'};
            app.SegmentosPrueba = uieditfield(app.EntrenamientoClasificadorTab, 'numeric');
            app.SegmentosPrueba.Limits = [0 90];
            app.SegmentosPrueba.ValueChangedFcn = createCallbackFcn(app, @SegmentosPruebaValueChanged, true);
            app.SegmentosPrueba.Value = 15;
            app.TextoPorcentaje = uilabel(app.EntrenamientoClasificadorTab);
            app.TextoPorcentaje.Text = '%';
            app.TextoPorcentaje2 = uilabel(app.EntrenamientoClasificadorTab);
            app.TextoPorcentaje2.Text = '%';
            app.TextoPorcentaje3 = uilabel(app.EntrenamientoClasificadorTab);
            app.TextoPorcentaje3.Text = '%';
            app.PARMETROSPARAUNCLASIFICADORRNALabel = uilabel(app.EntrenamientoClasificadorTab);
            app.PARMETROSPARAUNCLASIFICADORRNALabel.Text = 'PARÁMETROS PARA UN CLASIFICADOR RNA';
            app.DivisindedatosporValidacinCruzadaLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.DivisindedatosporValidacinCruzadaLabel.Text = {'División de datos por'; 'Validación Cruzada'};
            app.NmerodeParticionesKSpinnerLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.NmerodeParticionesKSpinnerLabel.Enable = 'off';
            app.NmerodeParticionesKSpinnerLabel.Text = {'Número de'; 'Particiones [K]'};
            app.NmerodeParticionesKSpinner = uispinner(app.EntrenamientoClasificadorTab);
            app.NmerodeParticionesKSpinner.Limits = [2 10];
            app.NmerodeParticionesKSpinner.Enable = 'off';
            app.NmerodeParticionesKSpinner.Value = 10;
            app.KernelaemplearLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.KernelaemplearLabel.Enable = 'off';
            app.KernelaemplearLabel.Text = {'Kernel'; 'a emplear'};
            app.KernelaemplearDropDown = uidropdown(app.EntrenamientoClasificadorTab);
            app.KernelaemplearDropDown.Items = {'gaussian', 'linear'};
            app.KernelaemplearDropDown.Enable = 'off';
            app.KernelaemplearDropDown.Value = 'gaussian';
            app.PARMETROSPARAUNCLASIFICADORSVMLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.PARMETROSPARAUNCLASIFICADORSVMLabel.Text = 'PARÁMETROS PARA UN CLASIFICADOR SVM';
            app.EntrenarelclasificadorespecificadoButton = uibutton(app.EntrenamientoClasificadorTab, 'push');
            app.EntrenarelclasificadorespecificadoButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarelclasificadorespecificadoButtonPushed, true);
            app.EntrenarelclasificadorespecificadoButton.Text = 'Entrenar el clasificador especificado';
            app.ExactituddelclasificadorLabel = uilabel(app.EntrenamientoClasificadorTab);
            app.ExactituddelclasificadorLabel.Text = 'Exactitud del clasificador: -%';
            app.GuardarclasificadorButton = uibutton(app.EntrenamientoClasificadorTab, 'push');
            app.GuardarclasificadorButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarclasificadorButtonPushed, true);
            app.GuardarclasificadorButton.Text = 'Guardar clasificador';
            % Show the figure after all components are created
            app.ClasificadordeanotacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = generacionalgoritmo(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ClasificadordeanotacionesUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ClasificadordeanotacionesUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClasificadordeanotacionesUIFigure)
        end
    end
end