classdef principal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Principal                     matlab.ui.Figure
        recuperarc                    matlab.ui.control.Button
        AnotacionesautomaticasButton  matlab.ui.control.Button
        ClasificadoresButton          matlab.ui.control.Button
        HerramientadeanlisisButton    matlab.ui.control.Button
        Conf                          matlab.ui.control.Button
        AdministrarusuariosButton     matlab.ui.control.Button
        DesconectarButton             matlab.ui.control.Button
        ConectarButton                matlab.ui.control.StateButton
        Image                         matlab.ui.control.Image
        EPILEPTICEEGANALYSISTOOLBOXLabel  matlab.ui.control.Label
        AnalizarpruebaButton          matlab.ui.control.Button
        ConsultarButton               matlab.ui.control.Button
        AgregarnuevopacienteButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Dialogo; % Description
        Conexion;
        AgregarPaciente;
        RealizarConsulta;
        AnalizarPrueba;
        Administrar;
        user;
        Configurar;
        pos = 0;
        CrearAlgoritmo;
        AnotacionesAutomaticas;
        Recon;
    end
    
    methods (Access = public)
        
        function activar(app, connect, admin)
            app.Conexion = connect;
            app.user = admin;
            query = ['SELECT * ' ...
                'FROM humana.usuarios WHERE usuario= "' ...
                admin...
                '"'];
            data = fetch(app.Conexion,query);
            if (data.admin_usuarios == 1)
                app.AdministrarusuariosButton.Enable = 'on';
            end
            if (data.analizar_prueba == 1)
                app.AnalizarpruebaButton.Enable = 'on';
            end
            if (data.ingreso_pacientes == 1)
                app.AgregarnuevopacienteButton.Enable = 'on';
            end
            if (data.consulta_pacientes == 1)
                app.ConsultarButton.Enable = 'on';
            end
            
            if (data.anotaciones_automaticas == 1)
                app.HerramientadeanlisisButton.Enable = 'on';
            end
            
            if (data.crear_algoritmo == 1)
                app.ClasificadoresButton.Enable = 'on';
            end
            
            if (data.anotaciones_automaticas2 == 1)
                app.AnotacionesautomaticasButton.Enable = 'on';
            end
            
            if (data.recuperar_contrasena == 1)
                app.recuperarc.Enable = 'on';
            end

            app.DesconectarButton.Enable = 'on';
            if (~strcmp(app.user,'resetpass'))
                app.Conf.Enable ="on";
            end
            
            %mostrar ventana para cambio de contraseña si es primer inicio
            %de sesión o nunca se ha cambiado la contraseña
            if (data.primer_login == 1)
                app.Configurar = configuracion(app, app.Conexion, app.user, true);
            end
        end
        
        function cancelarLogin(app)
            app.ConectarButton.Enable = 'on';
        end
        
        function ActivarAdminU(app)
            app.AdministrarusuariosButton.Enable = 'on';
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.Principal,"center");
            app.DesconectarButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.AdministrarusuariosButton.Enable = 'off';
            app.Conf.Enable = 'off';
            app.HerramientadeanlisisButton.Enable = 'off';
            app.ClasificadoresButton.Enable = 'off';
            app.AnotacionesautomaticasButton.Enable = 'off';
        end

        % Button pushed function: AgregarnuevopacienteButton
        function AgregarnuevopacienteButtonPushed(app, event)
%             % Deshabilitar botones
%             app.DesconectarButton.Enable = 'off';
%             app.AgregarnuevopacienteButton.Enable = 'off';
%             app.ConsultarButton.Enable = 'off';
%             app.AnalizarpruebaButton.Enable = 'off';
            
            app.AgregarPaciente = agregarpac(app,app.Conexion);
            
        end

        % Close request function: Principal
        function PrincipalCloseRequest(app, event)
            % Close database connection
            close(app.Conexion);
            %clear app.Conexion
            % Delete apps
%             delete(app.AgregarPaciente)
%             delete(app.RealizarConsulta)
%             delete(app.AnalizarPrueba)
            delete(app)
        end

        % Button pushed function: ConsultarButton
        function ConsultarButtonPushed(app, event)
            % Deshabilitar botones
%             app.DesconectarButton.Enable = 'off';
%             app.AgregarnuevopacienteButton.Enable = 'off';
%             app.ConsultarButton.Enable = 'off';
%             app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.RealizarConsulta = consultas(app,app.Conexion, app.user);
        end

        % Button pushed function: AnalizarpruebaButton
        function AnalizarpruebaButtonPushed(app, event)
            % Deshabilitar botones
%             app.DesconectarButton.Enable = 'off';
%             app.AgregarnuevopacienteButton.Enable = 'off';
%             app.ConsultarButton.Enable = 'off';
%             app.AnalizarpruebaButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.AnalizarPrueba = procesamiento(app,app.Conexion);
        end

        % Value changed function: ConectarButton
        function ConectarButtonValueChanged(app, event)
            app.ConectarButton.Enable = 'off';
            app.Dialogo = credenciales(app);
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            % Close database connection
            app.AgregarnuevopacienteButton.Enable = 'off';
            app.ConsultarButton.Enable = 'off';
            app.AnalizarpruebaButton.Enable = 'off';
            close(app.Conexion);
            clear app.Conexion
            app.ConectarButton.Enable = 'on';
            app.DesconectarButton.Enable = 'off';
            app.AdministrarusuariosButton.Enable = 'off';
            app.Conf.Enable = 'off';
            app.HerramientadeanlisisButton.Enable = 'off';
            app.AnotacionesautomaticasButton.Enable = 'off';
            app.ClasificadoresButton.Enable = 'off';
            app.ClasificadoresButton.Visible = 'off';
            app.AnotacionesautomaticasButton.Visible = 'off';
            app.recuperarc.Enable = 'off';
            app.HerramientadeanlisisButton.Position = [116,104,150,22];
            app.pos = 0;
        end

        % Button pushed function: AdministrarusuariosButton
        function AdministrarusuariosButtonPushed(app, event)
%             app.AdministrarusuariosButton.Enable = 'off';
            app.Administrar = admin(app, app.Conexion, app.user);
        end

        % Button pushed function: Conf
        function ConfButtonPushed(app, event)
            app.Configurar = configuracion(app, app.Conexion, app.user, false);
        end

        % Button pushed function: HerramientadeanlisisButton
        function HerramientadeanlisisButtonPushed(app, event)
            if app.pos == 0
                app.ClasificadoresButton.Visible = 'on';
                app.AnotacionesautomaticasButton.Visible = 'on';
                app.HerramientadeanlisisButton.Position = [38,105,150,22];
                app.pos = 1;
            else
                app.ClasificadoresButton.Visible = 'off';
                app.AnotacionesautomaticasButton.Visible = 'off';
                app.HerramientadeanlisisButton.Position = [116,104,150,22];
                app.pos = 0;
            end
            
        end

        % Button pushed function: ClasificadoresButton
        function ClasificadoresButtonPushed(app, event)
            app.CrearAlgoritmo = generacionalgoritmo(app);
        end

        % Button pushed function: AnotacionesautomaticasButton
        function AnotacionesautomaticasButtonPushed(app, event)
            app.AnotacionesAutomaticas = inicioanotacionesauto(app);
        end

        % Button pushed function: recuperarc
        function recuperarcButtonPushed(app, event)
            app.Recon = recuperar(app, app.Conexion);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Principal = uifigure('Visible', 'off');
            app.Principal.Name = 'Epileptic EEG Analysis Toolbox';
            app.Principal.Icon = fullfile(pathToMLAPP, 'icon_48.png');
            app.Principal.CloseRequestFcn = createCallbackFcn(app, @PrincipalCloseRequest, true);
            app.AgregarnuevopacienteButton = uibutton(app.Principal, 'push');
            app.AgregarnuevopacienteButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarnuevopacienteButtonPushed, true);
            app.AgregarnuevopacienteButton.Text = 'Agregar nuevo paciente';
            app.ConsultarButton = uibutton(app.Principal, 'push');
            app.ConsultarButton.ButtonPushedFcn = createCallbackFcn(app, @ConsultarButtonPushed, true);
            app.ConsultarButton.Text = 'Consultar';
            app.AnalizarpruebaButton = uibutton(app.Principal, 'push');
            app.AnalizarpruebaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizarpruebaButtonPushed, true);
            app.AnalizarpruebaButton.Text = 'Analizar prueba';
            app.EPILEPTICEEGANALYSISTOOLBOXLabel = uilabel(app.Principal);
            app.EPILEPTICEEGANALYSISTOOLBOXLabel.Text = 'EPILEPTIC EEG ANALYSIS TOOLBOX';
            app.Image = uiimage(app.Principal);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logouvg.jpg');
            app.ConectarButton = uibutton(app.Principal, 'state');
            app.ConectarButton.ValueChangedFcn = createCallbackFcn(app, @ConectarButtonValueChanged, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.Principal, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.AdministrarusuariosButton = uibutton(app.Principal, 'push');
            app.AdministrarusuariosButton.ButtonPushedFcn = createCallbackFcn(app, @AdministrarusuariosButtonPushed, true);
            app.AdministrarusuariosButton.Tooltip = {''};
            app.AdministrarusuariosButton.Text = 'Administrar usuarios';
            app.Conf = uibutton(app.Principal, 'push');
            app.Conf.ButtonPushedFcn = createCallbackFcn(app, @ConfButtonPushed, true);
            app.Conf.Icon = fullfile(pathToMLAPP, 'config.svg');
            app.Conf.Text = '';
            app.HerramientadeanlisisButton = uibutton(app.Principal, 'push');
            app.HerramientadeanlisisButton.ButtonPushedFcn = createCallbackFcn(app, @HerramientadeanlisisButtonPushed, true);
            app.HerramientadeanlisisButton.Text = 'Herramienta de análisis';
            app.ClasificadoresButton = uibutton(app.Principal, 'push');
            app.ClasificadoresButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificadoresButtonPushed, true);
            app.ClasificadoresButton.Visible = 'off';
            app.ClasificadoresButton.Text = 'Clasificadores';
            app.AnotacionesautomaticasButton = uibutton(app.Principal, 'push');
            app.AnotacionesautomaticasButton.ButtonPushedFcn = createCallbackFcn(app, @AnotacionesautomaticasButtonPushed, true);
            app.AnotacionesautomaticasButton.Visible = 'off';
            app.AnotacionesautomaticasButton.Text = 'Anotaciones automáticas';
            app.recuperarc = uibutton(app.Principal, 'push');
            app.recuperarc.ButtonPushedFcn = createCallbackFcn(app, @recuperarcButtonPushed, true);
            app.recuperarc.Icon = fullfile(pathToMLAPP, 'recuperar.png');
            app.recuperarc.Enable = 'off';
            app.recuperarc.Text = '';
            % Show the figure after all components are created
            app.Principal.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = principal

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Principal)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Principal)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Principal)
        end
    end
end