classdef selectprueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        seleccprueba            matlab.ui.Figure
        LeerButton              matlab.ui.control.Button
        RegresarButton          matlab.ui.control.Button
        PruebaEditField         matlab.ui.control.EditField
        PruebaEditFieldLabel    matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        PacienteEditField       matlab.ui.control.EditField
        PacienteEditFieldLabel  matlab.ui.control.Label
        BsquedaLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        datos
        Conexion % Description
    end
    
    properties (Access = public)
        frecuencia
        no_canales
        datadb % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, con)
            movegui(app.seleccprueba,"center");
            app.CallingApp = mainapp;
            global p
            p.MyData=[];
            app.Conexion = con;
        end

        % Button pushed function: LeerButton
        function LeerButtonPushed(app, event)
            global p
            id_paciente=app.PacienteEditField.Value;
            %------ Conexion con la base de datos------------------------
            conn = app.Conexion;
            query = ['SELECT * ' ...
                'FROM humana.pacientes WHERE pacientes.id_paciente= "' ...
                id_paciente...
                '"'];
            
            query2 = ['SELECT id_prueba, id_paciente, fecha_prueba, hora_inicio, duracion, frecuencia, no_canales ' ...
                'FROM humana.pruebas WHERE pruebas.id_paciente= "' ...
                id_paciente...
                '"'];
            
            data = fetch(conn,query);
            data2 = fetch(conn,query2);
            
            if(isempty(data)==1)
                errordlg('Paciente no encontrado','Error');
                ini = char(' ');
                p.MyData=[];
                app.PacienteEditField.Value = ini;

                app.UITable.Data = p.MyData;
                
            else
                % Mostrar datos en tablas
                dimension=size(data2);
                for i=1:dimension(1)
                    prueba = sprintf('%d',data2.id_prueba(i));
                    frecuencia = sprintf('%d',data2.frecuencia(i));
                    canales = sprintf('%d',data2.no_canales(i));
                    p.MyData=[p.MyData; [{i} {prueba} {frecuencia} {canales}]];
                end
                app.UITable.Data = p.MyData;
            end
            
            %Clear variables
            clear query query2
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed(app, event)
            id_prueba=app.PruebaEditField.Value;
            if (isempty(id_prueba))
                warndlg('Prueba no seleccionada','Advertencia');
            else
                %------ Conexion con la base de datos------------------------
                conn = app.Conexion;
                query = ['SELECT * ' ...
                    'FROM humana.pruebas WHERE pruebas.id_prueba= "' ...
                    id_prueba...
                    '"'];
                data = fetch(conn,query);
                app.CallingApp.freq = sprintf('%d',data.frecuencia);
                app.no_canales = sprintf('%d',data.no_canales);
                app.CallingApp.datos = deserialize(data.prueba{1,1});
                clear query
                app.CallingApp.VisualizarButton.Enable = 'on';
                app.CallingApp.ExtraerVectordeCaractersticasButton.Enable = 'on';
            end
            
            delete(app)
        end

        % Close request function: seleccprueba
        function seleccpruebaCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.seleccprueba = uifigure('Visible', 'off');
            app.seleccprueba.Name = 'Búsqueda desde la base de datos';
            app.seleccprueba.Icon = fullfile(pathToMLAPP, 'icons_resources', 'icon_48.png');
            app.seleccprueba.CloseRequestFcn = createCallbackFcn(app, @seleccpruebaCloseRequest, true);
            app.BsquedaLabel = uilabel(app.seleccprueba);
            app.BsquedaLabel.Text = 'Búsqueda';
            app.PacienteEditFieldLabel = uilabel(app.seleccprueba);
            app.PacienteEditFieldLabel.Text = 'Paciente';
            app.PacienteEditField = uieditfield(app.seleccprueba, 'text');
            app.UITable = uitable(app.seleccprueba);
            app.UITable.ColumnName = {'No.'; 'Prueba'; 'Frecuencia (Hz)'; 'No. Canales'};
            app.UITable.RowName = {};
            app.PruebaEditFieldLabel = uilabel(app.seleccprueba);
            app.PruebaEditFieldLabel.Text = 'Prueba';
            app.PruebaEditField = uieditfield(app.seleccprueba, 'text');
            app.RegresarButton = uibutton(app.seleccprueba, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed, true);
            app.RegresarButton.Text = 'Regresar';
            app.LeerButton = uibutton(app.seleccprueba, 'push');
            app.LeerButton.ButtonPushedFcn = createCallbackFcn(app, @LeerButtonPushed, true);
            app.LeerButton.Text = 'Leer';
            % Show the figure after all components are created
            app.seleccprueba.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectprueba(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.seleccprueba)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.seleccprueba)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.seleccprueba)
        end
    end
end